classdef GUI_deformation_ft_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1            matlab.ui.Figure
        uitoolbar1         matlab.ui.container.Toolbar
        uipushtool1        matlab.ui.container.toolbar.PushTool
        uitoggletool1      matlab.ui.container.toolbar.ToggleTool
        uitoggletool2      matlab.ui.container.toolbar.ToggleTool
        uitoggletool3      matlab.ui.container.toolbar.ToggleTool
        nameani_ellipse    matlab.ui.control.EditField
        anisotrpy_ellipse  matlab.ui.control.CheckBox
        editzoom           matlab.ui.control.EditField
        editsize           matlab.ui.control.EditField
        editstrel          matlab.ui.control.EditField
        editpropor         matlab.ui.control.EditField
        editcut            matlab.ui.control.EditField
        editsigma          matlab.ui.control.EditField
        enterwindow        matlab.ui.control.EditField
        textstrel          matlab.ui.control.Label
        choose_1           matlab.ui.control.CheckBox
        zoom               matlab.ui.control.Slider
        zoomt              matlab.ui.control.Label
        slidersize         matlab.ui.control.Slider
        num3               matlab.ui.control.Label
        num2               matlab.ui.control.Label
        num1               matlab.ui.control.Label
        regsize            matlab.ui.control.CheckBox
        choose             matlab.ui.control.CheckBox
        sizesub            matlab.ui.control.EditField
        register           matlab.ui.control.CheckBox
        adv                matlab.ui.control.CheckBox
        sizesstring        matlab.ui.control.Label
        sizes              matlab.ui.control.CheckBox
        text11             matlab.ui.control.Label
        text10             matlab.ui.control.Label
        text9              matlab.ui.control.Label
        text8              matlab.ui.control.Label
        text7              matlab.ui.control.Label
        window             matlab.ui.control.Slider
        shuffle            matlab.ui.control.CheckBox
        pushsigma          matlab.ui.control.Button
        strel              matlab.ui.control.Slider
        text5              matlab.ui.control.Label
        sigma              matlab.ui.control.Slider
        OK                 matlab.ui.control.CheckBox
        text3              matlab.ui.control.Label
        propor             matlab.ui.control.Slider
        text2              matlab.ui.control.Label
        cut                matlab.ui.control.Slider
        text18             matlab.ui.control.Label
        filt3              matlab.ui.control.UIAxes
        filt2              matlab.ui.control.UIAxes
        filt1              matlab.ui.control.UIAxes
        fft3propor         matlab.ui.control.UIAxes
        fft2propor         matlab.ui.control.UIAxes
        fft1propor         matlab.ui.control.UIAxes
        fft3cut            matlab.ui.control.UIAxes
        fft2cut            matlab.ui.control.UIAxes
        fft1cut            matlab.ui.control.UIAxes
        im2                matlab.ui.control.UIAxes
        im3                matlab.ui.control.UIAxes
        im1                matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function handles = affichcutfft(app, handles)
            
               imagesc(handles.fftsubcut1,'Parent',handles.fft1cut)
               axes(handles.fft1cut);
               zoom(handles.Param.zoom)
               set(handles.fft1cut,'Visible','off')
            
               imagesc(handles.fftsubcut2,'Parent',handles.fft2cut)
               axes(handles.fft2cut);
               zoom(handles.Param.zoom)
               set(handles.fft2cut,'Visible','off')
            
               imagesc(handles.fftsubcut3,'Parent',handles.fft3cut)
               axes(handles.fft3cut);
               zoom(handles.Param.zoom)
               set(handles.fft3cut,'Visible','off')
        end
        
        function handles = affichim(app, handles)
            
                a = choose_image(handles.Param.name{1},handles.Anamesi,handles.Param.tsart);
            
                 if handles.image1 ~=0
                     win1 = handles.image1;
                     win2 = handles.image2;
                     win3 = handles.image3;
                 elseif handles.regl == 1
                     win1 = 1;
                     win2 = 1;
                     win3 = 1;
                 else
                   win1 = round(rand(1)*handles.regl);
                   win2 = round(rand(1)*handles.regl);
                   win3 = round(rand(1)*handles.regl);
            
                 end
            
                 set(handles.num1,'String',['Number: ' num2str(win1)]);
                 set(handles.num2,'String',['Number: ' num2str(win2)]);
                 set(handles.num3,'String',['Number: ' num2str(win3)]);
            
                 x = handles.Posi(win1,1);
                 y = handles.Posi(win1,2);
            
                 handles.sub1=a(y:y+handles.Param.pas2-1,x:x+handles.Param.pas2-1);
                 imagesc(handles.sub1,'Parent',handles.im1)
                 set(handles.im1,'Visible','off')
            
                 x = handles.Posi(win2,1);
                 y = handles.Posi(win2,2);
                 handles.sub2=a(y:y+handles.Param.pas2-1,x:x+handles.Param.pas2-1);
                 imagesc(handles.sub2,'Parent',handles.im2)
                 set(handles.im2,'Visible','off')
            
                 x = handles.Posi(win3,1);
                 y = handles.Posi(win3,2);
                 handles.sub3=a(y:y+handles.Param.pas2-1,x:x+handles.Param.pas2-1);
                 imagesc(handles.sub3,'Parent',handles.im3)
                 set(handles.im3,'Visible','off')
                 handles.wins = [win1,win2,win3];
        end
        
        function handles = affichproporfft(app, handles)
            
               imagesc(handles.fftsubcutsp1,'Parent',handles.fft1propor)
               axes(handles.fft1propor);
               zoom(handles.Param.zoom)
            
               set(handles.fft1propor,'Visible','off')
            
               imagesc(handles.fftsubcutsp2,'Parent',handles.fft2propor)
                axes(handles.fft2propor);
               zoom(handles.Param.zoom)
               set(handles.fft2propor,'Visible','off')
            
               imagesc(handles.fftsubcutsp3,'Parent',handles.fft3propor)
                   axes(handles.fft3propor);
               zoom(handles.Param.zoom)
            
               set(handles.fft3propor,'Visible','off')
        end
        
        function handles = affichsize(app, handles)
            
                xo =  handles.Param.pas2/4;
                yo =  handles.Param.pas2/2;
            
                [~,E] = eig(handles.M1);
                L1 = sqrt(E(2,2)/2);
                L2 = sqrt(E(1,1)/2);
                lgE = -1/2*(log(L1/L2));
                Dev = handles.M1-1/2*trace(handles.M1)*eye(2);
                [V,~] = eig(Dev);
                ang=atan(V(1,2)/V(2,2));
                handles.angS = ang;
                handles.S = lgE;
            
                amp = handles.S;
                ang = handles.angS;
            
                x1 = xo+cos(ang+pi/2)*amp*handles.Param.scale;
                y1 = yo+sin(ang+pi/2)*amp*handles.Param.scale;
                x2 = xo-cos(ang+pi/2)*amp*handles.Param.scale;
                y2 = yo-sin(ang+pi/2)*amp*handles.Param.scale;
            
                X1 = [xo,x1];
                Y1 = [yo,y1];
                X2 = [xo,x2];
                Y2 = [yo,y2];
            
                imagesc(handles.sub1,'Parent',handles.im1)
                hold(handles.im1,'on');
                plot(X1,Y1,'Marker','.','LineStyle','-','Color','red','LineWidth',3,'Parent',handles.im1);
                plot(X2,Y2,'Marker','.','LineStyle','-','Color','red','LineWidth',3,'Parent',handles.im1);
                hold(handles.im1,'on');
                f_ellipseplotax(handles.Param.pas2/2*1/handles.ai1,handles.Param.pas2/2*1/handles.bi1,xo+1/2*handles.Param.pas2,yo,handles.phi1,'red',2,handles.im1);
            
            %     plot(handles.im1,handles.xu1+xo+1/2*handles.Param.pas2,handles.yu1+yo,'Marker','+','Color','yellow','MarkerSize',1,'LineStyle','none')
            %     Par =CircleFitByPratt([handles.xu1,handles.yu1]);
            %      hold(handles.im1,'on');
            %      viscircles(handles.im1,Par(1,1:2),Par(1,3));
              %  f_ellipseplotax(ai,bi,xo+1/2*handles.Param.pas2,yo+1/4*handles.Param.pas2,phi,'yellow',handles.im1)
            
            %
                set(handles.im1,'Visible','off')
                hold(handles.im1,'off');
            
            
                [~,E] = eig(handles.M2);
                L1 = 2*sqrt(E(2,2)/2);
                L2 = 2*sqrt(E(1,1)/2);
                lgE = -1/2*(log(L1/L2));
                Dev = handles.M2-1/2*trace(handles.M2)*eye(2);
                [V,~] = eig(Dev);
                ang=atan(V(1,2)/V(2,2));
                angS = ang;
                S = lgE;
            
                amp = S;
                ang = angS;
            
                x1 = xo+cos(ang+pi/2)*amp*handles.Param.scale;
                y1 = yo+sin(ang+pi/2)*amp*handles.Param.scale;
                x2 = xo-cos(ang+pi/2)*amp*handles.Param.scale;
                y2 = yo-sin(ang+pi/2)*amp*handles.Param.scale;
            
                X1 = [xo,x1];
                Y1 = [yo,y1];
                X2 = [xo,x2];
                Y2 = [yo,y2];
                axes(handles.im2)
                imagesc(handles.sub2,'Parent',handles.im2)
                hold(handles.im2,'on');
                plot(X1,Y1,'Marker','.','LineStyle','-','Color','red','LineWidth',3,'Parent',handles.im2);
                plot(X2,Y2,'Marker','.','LineStyle','-','Color','red','LineWidth',3,'Parent',handles.im2);
                   hold(handles.im2,'on');
            
               f_ellipseplotax(handles.Param.pas2/2*1/handles.ai2,handles.Param.pas2/2*1/handles.bi2,xo+1/2*handles.Param.pas2,yo,handles.phi2,'red',2,handles.im2);
                 hold(handles.im2,'off');
            set(handles.im2,'Visible','off')
            
                [~,E] = eig(handles.M3);
                L1 = sqrt(E(2,2)/2);
                L2 = sqrt(E(1,1)/2);
                lgE = -1/2*(log(L1/L2));
                Dev = handles.M3-1/2*trace(handles.M3)*eye(2);
                [V,~] = eig(Dev);
                ang=atan(V(1,2)/V(2,2));
                angS = ang;
                S = lgE;
            
                amp = S;
                ang = angS;
            
                x1 = xo+cos(ang+pi/2)*amp*handles.Param.scale;
                y1 = yo+sin(ang+pi/2)*amp*handles.Param.scale;
                x2 = xo-cos(ang+pi/2)*amp*handles.Param.scale;
                y2 = yo-sin(ang+pi/2)*amp*handles.Param.scale;
            
                X1 = [xo,x1];
                Y1 = [yo,y1];
                X2 = [xo,x2];
                Y2 = [yo,y2];
            
                imagesc(handles.sub3,'Parent',handles.im3)
                hold(handles.im3,'on');
                plot(X1,Y1,'Marker','.','LineStyle','-','Color','red','LineWidth',3,'Parent',handles.im3);
                plot(X2,Y2,'Marker','.','LineStyle','-','Color','red','LineWidth',3,'Parent',handles.im3);
                hold(handles.im3,'on');
                f_ellipseplotax(handles.Param.pas2/2*1/handles.ai3,handles.Param.pas2/2*1/handles.bi3,xo+1/2*handles.Param.pas2,yo,handles.phi3,'red',2,handles.im3);
                 set(handles.im3,'Visible','off')
                hold(handles.im3,'off');
        end
        
        function handles = affichsize_ellipsdef(app, handles)
            
                xo =  handles.Param.pas2/4;
                yo =  handles.Param.pas2/2;
            
            
                amp = 1/2*log(handles.bi1/handles.ai1);
                ang = handles.phi1+pi/2;
            
                x1 = xo+cos(ang)*amp*handles.Param.scale;
                y1 = yo+sin(ang)*amp*handles.Param.scale;
                x2 = xo-cos(ang)*amp*handles.Param.scale;
                y2 = yo-sin(ang)*amp*handles.Param.scale;
            
                X1 = [xo,x1];
                Y1 = [yo,y1];
                X2 = [xo,x2];
                Y2 = [yo,y2];
            
                imagesc(handles.sub1,'Parent',handles.im1)
                hold(handles.im1,'on');
                plot(X1,Y1,'Marker','.','LineStyle','-','Color','red','LineWidth',3,'Parent',handles.im1);
                plot(X2,Y2,'Marker','.','LineStyle','-','Color','red','LineWidth',3,'Parent',handles.im1);
                hold(handles.im1,'on');
                f_ellipseplotax(handles.Param.pas2/2*1/handles.ai1,handles.Param.pas2/2*1/handles.bi1,xo+1/2*handles.Param.pas2,yo,handles.phi1,'red',2,handles.im1);
            
                set(handles.im1,'Visible','off')
                hold(handles.im1,'off');
            
            
                amp = 1/2*log(handles.bi2/handles.ai2);
                ang = handles.phi2+pi/2;
            
                x1 = xo+cos(ang)*amp*handles.Param.scale;
                y1 = yo+sin(ang)*amp*handles.Param.scale;
                x2 = xo-cos(ang)*amp*handles.Param.scale;
                y2 = yo-sin(ang)*amp*handles.Param.scale;
            
                X1 = [xo,x1];
                Y1 = [yo,y1];
                X2 = [xo,x2];
                Y2 = [yo,y2];
            
                imagesc(handles.sub2,'Parent',handles.im2)
                hold(handles.im2,'on');
                plot(X1,Y1,'Marker','.','LineStyle','-','Color','red','LineWidth',3,'Parent',handles.im2);
                plot(X2,Y2,'Marker','.','LineStyle','-','Color','red','LineWidth',3,'Parent',handles.im2);
                hold(handles.im2,'on');
                f_ellipseplotax(handles.Param.pas2/2*1/handles.ai2,handles.Param.pas2/2*1/handles.bi2,xo+1/2*handles.Param.pas2,yo,handles.phi2,'red',2,handles.im2);
                set(handles.im2,'Visible','off')
                hold(handles.im2,'off');


                amp = 1/2*log(handles.bi3/handles.ai3);
                ang = handles.phi3+pi/2;
            
                x1 = xo+cos(ang)*amp*handles.Param.scale;
                y1 = yo+sin(ang)*amp*handles.Param.scale;
                x2 = xo-cos(ang)*amp*handles.Param.scale;
                y2 = yo-sin(ang)*amp*handles.Param.scale;
            
                X1 = [xo,x1];
                Y1 = [yo,y1];
                X2 = [xo,x2];
                Y2 = [yo,y2];
            
                imagesc(handles.sub3,'Parent',handles.im3)
                hold(handles.im3,'on');
                plot(X1,Y1,'Marker','.','LineStyle','-','Color','red','LineWidth',3,'Parent',handles.im3);
                plot(X2,Y2,'Marker','.','LineStyle','-','Color','red','LineWidth',3,'Parent',handles.im3);
                hold(handles.im3,'on');
                f_ellipseplotax(handles.Param.pas2/2*1/handles.ai3,handles.Param.pas2/2*1/handles.bi3,xo+1/2*handles.Param.pas2,yo,handles.phi3,'red',2,handles.im3);
                set(handles.im3,'Visible','off')
                hold(handles.im3,'off');
                   axes(handles.fft3cut);
                zoom(handles.Param.zoom);
            
                hold(handles.fft3cut,'off');
        end
        
        function handles = calculregion(app, handles)
            
            [handles.Posi,handles.regl] = Position_gui(handles.Param);
        end
        
        function handles = cutfun(app, handles)
                handles.fftsubcut1 = handles.spsub1;
                handles.fftsubcut2 = handles.spsub2;
                handles.fftsubcut3 = handles.spsub3;
             ic = handles.Param.pas2*1/2+1;
             jc = handles.Param.pas2*1/2+1;
             [I,J] = ndgrid(1:handles.Param.pas2,1:handles.Param.pas2);
             M = double((I-ic).^2+(J-jc).^2<=(handles.Param.cut)^2);
             handles.fftsubcut1(M>0) = 0;
             handles.fftsubcut2(M>0) = 0;
             handles.fftsubcut3(M>0) = 0;
        end
        
        function edit1_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edit1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
             guidata(hObject,handles);
        end
        
        function handles = ellipses_def(app, handles)
                abs_im_fft_w = handles.fftsubcut1;
                [yu,xu] = localMaximum_h(abs_im_fft_w,1,0,handles.Param.nbpoints);
            
                [theta,rho]=cart2pol(xu-handles.Param.pas2/2,yu-handles.Param.pas2/2);
                handles.xu1=handles.Param.pas2./(2.*rho).*cos(theta);
                handles.yu1=handles.Param.pas2./(2.*rho).*sin(theta);
            
                set(handles.fft1cut,'Visible','on')
                imagesc(handles.fftsubcut1,'Parent',handles.fft1cut)
                hold(handles.fft1cut,'on');
                plot(handles.fft1cut,xu,yu,'Marker','+','Color','black','MarkerSize',10,'LineStyle','none')
            
                [~,~,ai,bi,phi,~]=ellipsefit(xu,yu);
                hold(handles.fft1cut,'on');
                f_ellipseplotax(ai,bi,handles.Param.pas2/2+1,handles.Param.pas2/2+1,phi,'red',2,handles.fft1cut);
                handles.ai1 = ai;
                handles.bi1 =bi;
                handles.phi1 = phi;
                set(handles.fft1cut,'Visible','off')
                axes(handles.fft1cut);
                zoom(handles.Param.zoom)
            
                hold(handles.fft1cut,'off');
               clear xu yu
            
                 abs_im_fft_w = handles.fftsubcut2;
                [yu,xu] = localMaximum_h(abs_im_fft_w,1,0,handles.Param.nbpoints);
            
                set(handles.fft2cut,'Visible','on')
                imagesc(handles.fftsubcut2,'Parent',handles.fft2cut)
                hold(handles.fft2cut,'on');
                plot(handles.fft2cut,xu,yu,'Marker','+','Color','black','MarkerSize',10,'LineStyle','none')
            
                [~,~,ai,bi,phi,~]=ellipsefit(xu,yu);
                hold(handles.fft2cut,'on');
                f_ellipseplotax(ai,bi,handles.Param.pas2/2+1,handles.Param.pas2/2+1,phi,'red',2,handles.fft2cut);
                  handles.ai2 = ai;
                handles.bi2 =bi;
                handles.phi2 = phi;
            
                set(handles.fft2cut,'Visible','off')
               axes(handles.fft2cut);
                zoom(handles.Param.zoom)
            
                hold(handles.fft2cut,'off');
               clear xu yu
            
            
                 abs_im_fft_w = handles.fftsubcut3;
                [yu,xu] = localMaximum_h(abs_im_fft_w,1,0,handles.Param.nbpoints);
            
                set(handles.fft3cut,'Visible','on')
                imagesc(handles.fftsubcut3,'Parent',handles.fft3cut)
                hold(handles.fft3cut,'on');
                plot(handles.fft3cut,xu,yu,'Marker','+','Color','black','MarkerSize',10,'LineStyle','none')
            
                [~,~,ai,bi,phi,~]=ellipsefit(xu,yu);
                hold(handles.fft3cut,'on');
                f_ellipseplotax(ai,bi,handles.Param.pas2/2+1,handles.Param.pas2/2+1,phi,'red',2,handles.fft3cut);
                  handles.ai3 = ai;
                handles.bi3 =bi;
                handles.phi3 = phi;
            
                set(handles.fft3cut,'Visible','off')
               axes(handles.fft3cut);
                zoom(handles.Param.zoom);
            
                hold(handles.fft3cut,'off');
        end
        
        function handles = fftcalculus(app, handles)
            % asubs contains the subimages we will use
                 FTs = avfft(handles.Param.name,handles.wins,handles.Param,handles.Posi);
                 handles.fftsub1 = reshape(FTs(1,:,:),handles.Param.pas2,handles.Param.pas2);
                handles.fftsub2 = reshape(FTs(2,:,:),handles.Param.pas2,handles.Param.pas2);
                handles.fftsub3 = reshape(FTs(3,:,:),handles.Param.pas2,handles.Param.pas2);
        end
        
        function handles = filter(app, handles)
               imagesc(handles.fftsubcutspse1,'Parent',handles.filt1);
                    axes(handles.filt1);
                zoom(handles.Param.zoom)
                set(handles.filt1,'Visible','off');
            
               imagesc(handles.fftsubcutspse2,'Parent',handles.filt2);
                       axes(handles.filt2);
                zoom(handles.Param.zoom)
            
                set(handles.filt2,'Visible','off');
            
               imagesc(handles.fftsubcutspse3,'Parent',handles.filt3);
                       axes(handles.filt3);
                zoom(handles.Param.zoom)
                set(handles.filt3,'Visible','off');
        end
        
        function handles = gaussfilt(app, handles)
                filtre = gaussianFilter(ceil(2*handles.Param.sigma),handles.Param.sigma);
                handles.spsub1 = filter2(filtre,handles.fftsub1);
                handles.spsub2 = filter2(filtre,handles.fftsub2);
                handles.spsub3 = filter2(filtre,handles.fftsub3);
        end
        
        function handles = proporfft(app, handles)
                 ic = handles.Param.pas2/2+1;
                 jc = handles.Param.pas2/2+1;
                 [I,J] = ndgrid(1:handles.Param.pas2,1:handles.Param.pas2);
                 M = double((I-ic).^2+(J-jc).^2<=(handles.Param.cut)^2);
            
                handles.fftsubcutsp1 = handles.fftsubcut1;
                handles.fftsubcutsp2 = handles.fftsubcut2;
                handles.fftsubcutsp3 = handles.fftsubcut3;
            
            
                reshaped = reshape(handles.fftsubcutsp1,[handles.Param.pas2*handles.Param.pas2 ,1]);         % Colonnes par colonnes donc l'indexage en
                [~,index]= sort(reshaped ,'descend');
                nbel = handles.Param.propor * handles.Param.pas2*handles.Param.pas2;
                if nbel > handles.Param.pas2*handles.Param.pas2
                    nbel =  handles.Param.pas2*handles.Param.pas2;
                else
                end
            
                handles.fftsubcutsp1(index(1:floor(nbel))) = 1;
                handles.fftsubcutsp1(index(floor(nbel)+1:end)) = 0;
            
             handles.fftsubcutsp1(M>0) = 1;
            
                spsubcutfilt = handles.fftsubcutsp1;
                B = false(handles.Param.pas2);
                B(spsubcutfilt>0)=1;
                se=strel('disk',handles.Param.strel);
                handles.fftsubcutspse1=imclose(B,se);
                spsubcutfilt =  handles.fftsubcutspse1;
            
                norm = sum(sum((spsubcutfilt).^2));
                [Yg,Xg]=meshgrid(1:(handles.Param.pas2),1:(handles.Param.pas2));
                xc = handles.Param.pas2/2;
                yc = handles.Param.pas2/2;
                Xo = Xg-xc;
                Yo = Yg-yc;
                Y = (Yg-yc).^2; % Lignes
                X = (Xg-xc).^2; % Colonnes
                YY = sum(sum((spsubcutfilt).^2.*Y))/norm;
                XX = sum(sum((spsubcutfilt).^2.*X))/norm;
                XY = sum(sum((spsubcutfilt).^2.*Xo.*Yo))/norm;
                handles.M1 = [XX,XY;XY,YY];
            
                 reshaped = reshape(handles.fftsubcutsp2,[handles.Param.pas2*handles.Param.pas2 ,1]);         % Colonnes par colonnes donc l'indexage en
                [~,index]= sort(reshaped ,'descend');
                nbel = handles.Param.propor * handles.Param.pas2*handles.Param.pas2;
                if nbel > handles.Param.pas2*handles.Param.pas2
                    nbel =  handles.Param.pas2*handles.Param.pas2;
                else
                end
            
                handles.fftsubcutsp2(index(1:floor(nbel))) = 1;
                handles.fftsubcutsp2(index(floor(nbel)+1:end)) = 0;
                 handles.fftsubcutsp2(M>0) = 1;
            
                spsubcutfilt = handles.fftsubcutsp2;
            
                B = false(handles.Param.pas2);
                B(spsubcutfilt>0)=1;
                se=strel('disk',handles.Param.strel);
                handles.fftsubcutspse2=imclose(B,se);
                spsubcutfilt =  handles.fftsubcutspse2;
            
                norm = sum(sum((spsubcutfilt).^2));
                [Yg,Xg]=meshgrid(1:(handles.Param.pas2),1:(handles.Param.pas2));
                xc = handles.Param.pas2/2;
                yc = handles.Param.pas2/2;
                Xo = Xg-xc;
                Yo = Yg-yc;
                Y = (Yg-yc).^2; % Lignes
                X = (Xg-xc).^2; % Colonnes
                YY = sum(sum((spsubcutfilt).^2.*Y))/norm;
                XX = sum(sum((spsubcutfilt).^2.*X))/norm;
                XY = sum(sum((spsubcutfilt).^2.*Xo.*Yo))/norm;
                handles.M2 = [XX,XY;XY,YY];
            
                reshaped = reshape(handles.fftsubcutsp3,[handles.Param.pas2*handles.Param.pas2,1]);         % Colonnes par colonnes donc l'indexage en
                [~,index]= sort(reshaped ,'descend');
                nbel = handles.Param.propor * handles.Param.pas2*handles.Param.pas2;
                if nbel > handles.Param.pas2*handles.Param.pas2
                    nbel =  handles.Param.pas2*handles.Param.pas2;
                else
                end
            
                handles.fftsubcutsp3(index(1:floor(nbel))) = 1;
                handles.fftsubcutsp3(index(floor(nbel)+1:end)) = 0;
                handles.fftsubcutsp3(M>0) = 1;
            
                spsubcutfilt = handles.fftsubcutsp3;
                B = false(handles.Param.pas2);
                B(spsubcutfilt>0)=1;
                se=strel('disk',handles.Param.strel);
                handles.fftsubcutspse3=imclose(B,se);
                spsubcutfilt =  handles.fftsubcutspse3;
            
                norm = sum(sum((spsubcutfilt).^2));
                [Yg,Xg]=meshgrid(1:(handles.Param.pas2),1:(handles.Param.pas2));
                xc = handles.Param.pas2/2;
                yc = handles.Param.pas2/2;
                Xo = Xg-xc;
                Yo = Yg-yc;
                Y = (Yg-yc).^2; % Lignes
                X = (Xg-xc).^2; % Colonnes
                YY = sum(sum((spsubcutfilt).^2.*Y))/norm;
                XX = sum(sum((spsubcutfilt).^2.*X))/norm;
                XY = sum(sum((spsubcutfilt).^2.*Xo.*Yo))/norm;
                handles.M3 = [XX,XY;XY,YY];
        end
        
        function handles = trace_defo(app, handles)
            
                xo =  handles.Param.pas2/2;
                yo =  handles.Param.pas2/2;
            
                [~,E] = eig(handles.M1);
                L1 = 2*sqrt(E(2,2)/2);
                L2 = 2*sqrt(E(1,1)/2);
                lgE = -1/2*(log(L1/L2));
                Dev = handles.M1-1/2*trace(handles.M1)*eye(2);
                [V,~] = eig(Dev);
                ang=atan(V(1,2)/V(2,2));
                handles.angS = ang;
                handles.S = lgE;
            
                amp = handles.S;
                ang = handles.angS;
            
                x1 = xo+cos(ang+pi/2)*amp*handles.Param.scale;
                y1 = yo+sin(ang+pi/2)*amp*handles.Param.scale;
                x2 = xo-cos(ang+pi/2)*amp*handles.Param.scale;
                y2 = yo-sin(ang+pi/2)*amp*handles.Param.scale;
            
                X1 = [xo,x1];
                Y1 = [yo,y1];
                X2 = [xo,x2];
                Y2 = [yo,y2];
            
                imagesc(handles.sub1,'Parent',handles.im1)
                hold(handles.im1,'on');
                plot(X1,Y1,'Marker','.','LineStyle','-','Color','red','LineWidth',3,'Parent',handles.im1);
                plot(X2,Y2,'Marker','.','LineStyle','-','Color','red','LineWidth',3,'Parent',handles.im1);
                set(handles.im1,'Visible','off')
                hold(handles.im1,'off');
            
            
                [~,E] = eig(handles.M2);
                L1 = 2*sqrt(E(2,2)/2);
                L2 = 2*sqrt(E(1,1)/2);
                lgE = -1/2*(log(L1/L2));
                Dev = handles.M2-1/2*trace(handles.M2)*eye(2);
                [V,~] = eig(Dev);
                ang=atan(V(1,2)/V(2,2));
                angS = ang;
                S = lgE;
            
                amp = S;
                ang = angS;
            
                x1 = xo+cos(ang+pi/2)*amp*handles.Param.scale;
                y1 = yo+sin(ang+pi/2)*amp*handles.Param.scale;
                x2 = xo-cos(ang+pi/2)*amp*handles.Param.scale;
                y2 = yo-sin(ang+pi/2)*amp*handles.Param.scale;
            
                X1 = [xo,x1];
                Y1 = [yo,y1];
                X2 = [xo,x2];
                Y2 = [yo,y2];
            
                imagesc(handles.sub2,'Parent',handles.im2)
                hold(handles.im2,'on');
                plot(X1,Y1,'Marker','.','LineStyle','-','Color','red','LineWidth',3,'Parent',handles.im2);
                plot(X2,Y2,'Marker','.','LineStyle','-','Color','red','LineWidth',3,'Parent',handles.im2);
                set(handles.im2,'Visible','off')
                hold(handles.im2,'off');
            
            
                [~,E] = eig(handles.M3);
                L1 = 2*sqrt(E(2,2)/2);
                L2 = 2*sqrt(E(1,1)/2);
                lgE = -1/2*(log(L1/L2));
                Dev = handles.M3-1/2*trace(handles.M3)*eye(2);
                [V,~] = eig(Dev);
                ang=atan(V(1,2)/V(2,2));
                angS = ang;
                S = lgE;
            
                amp = S;
                ang = angS;
            
                x1 = xo+cos(ang+pi/2)*amp*handles.Param.scale;
                y1 = yo+sin(ang+pi/2)*amp*handles.Param.scale;
                x2 = xo-cos(ang+pi/2)*amp*handles.Param.scale;
                y2 = yo-sin(ang+pi/2)*amp*handles.Param.scale;
            
                X1 = [xo,x1];
                Y1 = [yo,y1];
                X2 = [xo,x2];
                Y2 = [yo,y2];
            
                imagesc(handles.sub3,'Parent',handles.im3)
                hold(handles.im3,'on');
                plot(X1,Y1,'Marker','.','LineStyle','-','Color','red','LineWidth',3,'Parent',handles.im3);
                plot(X2,Y2,'Marker','.','LineStyle','-','Color','red','LineWidth',3,'Parent',handles.im3);
                set(handles.im3,'Visible','off')
                hold(handles.im3,'off');
        end
        
        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)
            
            % Set component properties that require runtime configuration
            app.choose.ValueChangedFcn = '0';
            app.uipushtool1.ClickedCallback = 'filemenufcn(gcbf,''FileSave'')';
            app.uitoggletool1.ClickedCallback = 'putdowntext(''pan'',gcbo)';
            app.uitoggletool2.ClickedCallback = 'putdowntext(''zoomin'',gcbo)';
            app.uitoggletool3.ClickedCallback = 'putdowntext(''zoomout'',gcbo)';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function GUI_deformation_ft_OpeningFcn(app, varargin)
            % --- Executes just before GUI_deformation_ft is made visible.
            
            % Add runtime required configuration - Added by Migration Tool
            addRuntimeConfigurations(app);
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to GUI_deformation_ft (see VARARGIN)
            
            % Choose default command line output for GUI_deformation_ft
            handles.output = hObject;
            
            handles.Param=varargin{1};
            
            handles.Anamesi = cleanfolder([handles.Param.name{1}]);
            
            handles.image1 = 0;
            handles.image2 =0;
            handles.image3 =0;
            handles.Param.regsize = 0;
            handles.Param.cut = 0;
            handles.Param.register = 0;
            handles.Param.propor = 0.1;
            handles.Param.sigma = 0.1;
            handles.Param.strel = 4;
            handles.Param.pas2 = 128;
            handles.Param.zoom = 6;
            
            handles.rec = (1-handles.Param.rec)*handles.Param.pas2;
            handles.Param.nbpoints = 30;
            handles.Param.sizeview = 0;
            
            handles = calculregion(app, handles);
            handles = affichim(app, handles);
            handles = fftcalculus(app, handles);
            handles = gaussfilt(app, handles);
            handles = cutfun(app, handles);
            handles = affichcutfft(app, handles);
            handles = proporfft(app, handles);
            handles = affichproporfft(app, handles);
            handles = filter(app, handles);
            handles = trace_defo(app, handles);
            set(get(handles.filt1,'children'),'visible','off')
            set(get(handles.filt2,'children'),'visible','off')
            set(get(handles.filt3,'children'),'visible','off')
            
            KB=handles.Param.pas2^2*9.53674e-7*8;
            KBwhole  = KB*handles.regl;
            set(handles.sizesub,'String',sprintf(['One image: ' num2str(floor(KBwhole)) ' MB']));
            % Update handles structure
            guidata(hObject, handles);
        end

        % Value changed function: OK
        function OK_Callback(app, event)
            % --- Executes on button press in OK.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to OK (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            Val = get(hObject,'Value');
            if Val == 1
               % Parameters = struct('propor',handles.propor,'cut',handles.cut,'strel',handles.strel,'sigma',handles.sigma,'pas2',handles.Param.pas2,'registering',handles.register);
                Parameters=handles.Param;
                save([handles.Param.pathout,'Param.mat'],'Parameters');
            
               savefig(handles.figure1,[handles.Param.pathout,'Parameters_fig.fig'])     % save image fig
            
                hgexport(handles.figure1,[handles.Param.pathout,'Parameters_fig.png'],hgexport('factorystyle'),'Format','png');
            
                close(handles.figure1);
            
                full_analysis(handles.Param);
            else
            end
        end

        % Value changed function: adv
        function adv_Callback(app, event)
            % --- Executes on button press in adv.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to adv (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of adv
            if get(hObject,'Value')
                set(handles.editstrel,'Visible','On')
                set(handles.regsize,'Visible','On')
                set(handles.textstrel,'Visible','On')
                set(handles.strel,'Visible','On')
                set(handles.text11,'Visible','On')
                set(get(handles.filt1,'children'),'visible','on')
                set(get(handles.filt2,'children'),'visible','on')
                set(get(handles.filt3,'children'),'visible','on')
                set(handles.text18,'visible','on')
                set(handles.sizes,'visible','on')
                set(handles.slidersize,'visible','on')
                 set(handles.editsize,'visible','on')
                 set(handles.anisotrpy_ellipse,'Visible','On')
                set(handles.nameani_ellipse,'Visible','On')
            
                 handles = filter(app, handles);
            
            else
                set(handles.editstrel,'Visible','Off')
                  set(handles.regsize,'Visible','Off')
                set(handles.textstrel,'Visible','Off')
                set(handles.strel,'Visible','Off')
                 set(handles.text18,'visible','Off')
                   set(handles.anisotrpy_ellipse,'Visible','Off')
                set(handles.nameani_ellipse,'Visible','Off')
            
                set(handles.text11,'Visible','Off')
                set(get(handles.filt1,'children'),'visible','off')
                set(get(handles.filt2,'children'),'visible','off')
                set(get(handles.filt3,'children'),'visible','off')
                set(handles.sizesstring,'visible','off')
                set(handles.sizes,'visible','off')
                set(handles.slidersize,'visible','off')
             set(handles.editsize,'visible','off')
            
            end
            guidata(hObject,handles);
        end

        % Value changed function: anisotrpy_ellipse
        function anisotrpy_ellipse_Callback(app, event)
            % --- Executes on button press in anisotrpy_ellipse.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to anisotrpy_ellipse (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of anisotrpy_ellipse
            handles.Param.ani_ellipse = get(hObject,'Value');
            
            if get(hObject,'Value') &&  get(handles.sizes,'Value')
                handles = ellipses_def(app, handles);
                handles = affichsize_ellipsdef(app, handles);
            end
            if get(hObject,'Value')==0 &&  get(handles.sizes,'Value')
                handles = ellipses_def(app, handles);
                handles = affichsize(app, handles);
            end
            if get(hObject,'Value')==0 &&  get(handles.sizes,'Value')==0
                handles = affichcutfft(app, handles);
                handles = trace_defo(app, handles);
            end
            
            guidata(hObject,handles);
        end

        % Value changed function: choose_1
        function choose_Callback(app, event)
            % --- Executes on button press in choose.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to choose (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of choose
                 a = imread([handles.Param.name,  handles.Anamesi(handles.Param.tsart).name]);
                 a = im2double(a);
            
            if get(hObject,'Value')
                F = figure(2);
                imshow(a)
                hold on
                for win = 1:2:handles.regl
                   x = handles.Posi(win,1)+handles.Param.pas2/2;
                   y = handles.Posi(win,2)+handles.Param.pas2/2;
                   text(x,y,num2str(win),'Color','yellow','FontSize',5);
            
                end
                Numbs = choose();
                waitfor(Numbs.OK,'Value',1)
                handles.image1 = str2double(Numbs.NB1.String);
                handles.image2 = str2double(Numbs.NB2.String);
                handles.image3 = str2double(Numbs.NB3.String);
                close(Numbs.figure1);
            %        handles.image1 = input('What is the number of the first subimage? ');
            %        handles.image2 = input('What is the number of the second subimage? ');
            %        handles.image3 = input('What is the number of the third subimage? ');
                 close(F);
                    handles = calculregion(app, handles);
                    handles = affichim(app, handles);
                    handles = fftcalculus(app, handles);
                    handles = gaussfilt(app, handles);
                    handles = cutfun(app, handles);
                    handles = affichcutfft(app, handles);
                    handles = proporfft(app, handles);
                    handles = affichproporfft(app, handles);
                    handles = trace_defo(app, handles);
                 if get(handles.sizes,'Value') && get(handles.anisotrpy_ellipse,'Value')==0
            
                    handles = ellipses_def(app, handles);
                    handles = affichsize(app, handles);
                else
                end
                if get(handles.sizes,'Value') && get(handles.anisotrpy_ellipse,'Value')
                    handles = ellipses_def(app, handles);
                    handles = affichsize_ellipsdef(app, handles);
                end
            
                    if handles.adv == 1
                        handles = filter(app, handles);
                    else
                        set(get(handles.filt1,'children'),'visible','off')
                        set(get(handles.filt2,'children'),'visible','off')
                        set(get(handles.filt3,'children'),'visible','off')
            
                    end
            else
                handles.image1 = 0;
                handles.image2 =0;
                handles.image3 =0;
                    handles = calculregion(app, handles);
                    handles = affichim(app, handles);
                    handles = fftcalculus(app, handles);
                    handles = gaussfilt(app, handles);
                    handles = cutfun(app, handles);
                    handles = affichcutfft(app, handles);
                    handles = proporfft(app, handles);
                    handles = affichproporfft(app, handles);
                    handles = trace_defo(app, handles);
                if get(handles.sizes,'Value') && get(handles.anisotrpy_ellipse,'Value')==0
            
                    handles = ellipses_def(app, handles);
                    handles = affichsize(app, handles);
                else
                end
                if get(handles.sizes,'Value') && get(handles.anisotrpy_ellipse,'Value')
                    handles = ellipses_def(app, handles);
                    handles = affichsize_ellipsdef(app, handles);
                end
            
            
                    if handles.adv == 1
                        handles = filter(app, handles);
                    else
                        set(get(handles.filt1,'children'),'visible','off')
                        set(get(handles.filt2,'children'),'visible','off')
                        set(get(handles.filt3,'children'),'visible','off')
            
                    end
            end
            guidata(hObject, handles);
        end

        % Value changed function: cut
        function cut_Callback(app, event)
            % --- Executes on slider movement.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to cut (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            newval = floor(get(hObject,'Value'));
            if  newval ~= handles.Param.cut
                set(hObject,'Value',newval);
                handles.Param.cut=newval;
                handles = gaussfilt(app, handles);
                handles = cutfun(app, handles);
                handles = affichcutfft(app, handles);
                set(handles.editcut,'String',handles.Param.cut);
            
            
                if get(handles.sizes,'Value') && get(handles.anisotrpy_ellipse,'Value')==0
            
                    handles = ellipses_def(app, handles);
                    handles = affichsize(app, handles);
                else
                end
                if get(handles.sizes,'Value') && get(handles.anisotrpy_ellipse,'Value')
                    handles = ellipses_def(app, handles);
                    handles = affichsize_ellipsdef(app, handles);
                end
            
            else
            end
            guidata(hObject, handles);
        end

        % Value changed function: editcut
        function editcut_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to editcut (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of editcut as text
            %        str2double(get(hObject,'String')) returns contents of editcut as a double
            newval = str2double(get(hObject,'String'));
            set(handles.cut,'Value',newval);
            if  newval ~= handles.Param.cut
                set(hObject,'Value',newval);
                handles.Param.cut=newval;
                handles = gaussfilt(app, handles);
                handles = cutfun(app, handles);
                handles = affichcutfft(app, handles);
                if get(handles.sizes,'Value') && get(handles.anisotrpy_ellipse,'Value')==0
            
                    handles = ellipses_def(app, handles);
                    handles = affichsize(app, handles);
                else
                end
                if get(handles.sizes,'Value') && get(handles.anisotrpy_ellipse,'Value')
                    handles = ellipses_def(app, handles);
                    handles = affichsize_ellipsdef(app, handles);
                end
            
            else
            end
            guidata(hObject, handles);
        end

        % Value changed function: editpropor
        function editpropor_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to editpropor (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of editpropor as text
            %        str2double(get(hObject,'String')) returns contents of editpropor as a double
                handles.Param.propor = str2double(get(hObject,'String'));
                set(handles.propor,'Value',handles.Param.propor*10^3);
               % handles.propor = handles.propor*10^-3;
                handles = proporfft(app, handles);
                handles = affichproporfft(app, handles);
                handles = trace_defo(app, handles);
            
                if get(handles.sizes,'Value') && get(handles.anisotrpy_ellipse,'Value')==0
            
                    handles = ellipses_def(app, handles);
                    handles = affichsize(app, handles);
                else
                end
                if get(handles.sizes,'Value') && get(handles.anisotrpy_ellipse,'Value')
                    handles = ellipses_def(app, handles);
                    handles = affichsize_ellipsdef(app, handles);
                end
                if get(handles.adv,'Value')
                handles = filter(app, handles);
                else
                end
            
            guidata(hObject,handles);
        end

        % Value changed function: editsigma
        function editsigma_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to editsigma (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of editsigma as text
            %        str2double(get(hObject,'String')) returns contents of editsigma as a double
                newval = str2double(get(hObject,'String'));
                handles.Param.sigma = newval;
                handles = fftcalculus(app, handles);
                handles = gaussfilt(app, handles);
                handles = cutfun(app, handles);
                handles = affichcutfft(app, handles);
                set(handles.sigma,'Value',newval);
                if get(handles.sizes,'Value') && get(handles.anisotrpy_ellipse,'Value')==0
            
                    handles = ellipses_def(app, handles);
                    handles = affichsize(app, handles);
                else
                end
                if get(handles.sizes,'Value') && get(handles.anisotrpy_ellipse,'Value')
                    handles = ellipses_def(app, handles);
                    handles = affichsize_ellipsdef(app, handles);
                end
            
            
            guidata(hObject,handles);
        end

        % Value changed function: editsize
        function editsize_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to editsize (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of editsize as text
            %        str2double(get(hObject,'String')) returns contents of editsize as a double
            
            handles.Param.nbpoints = str2double(get(hObject,'String'));
            set(handles.slidersize,'Value',handles.Param.nbpoints);
                if get(handles.sizes,'Value') && get(handles.anisotrpy_ellipse,'Value')==0
            
                    handles = ellipses_def(app, handles);
                    handles = affichsize(app, handles);
                else
                end
                if get(handles.sizes,'Value') && get(handles.anisotrpy_ellipse,'Value')
                    handles = ellipses_def(app, handles);
                    handles = affichsize_ellipsdef(app, handles);
                end
            
            % if handles.Param.sizeview==1
            %     handles = ellipses_def(handles);
            %     handles = affichsize(handles);
            % else
            %     handles = affichcutfft(handles);
            %     handles = trace_defo(handles);
            % end
            guidata(hObject, handles);
        end

        % Value changed function: editstrel
        function editstrel_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to editstrel (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of editstrel as text
            %        str2double(get(hObject,'String')) returns contents of editstrel as a double
                newval = str2double(get(hObject,'String'));
                set(handles.strel,'Value',newval);
                if newval > 4 && newval ~= handles.Param.strel
                    handles.Param.strel = newval;
                    handles = proporfft(app, handles);
                    handles = affichproporfft(app, handles);
                    handles = trace_defo(app, handles);
                if get(handles.sizes,'Value') && get(handles.anisotrpy_ellipse,'Value')==0
            
                    handles = ellipses_def(app, handles);
                    handles = affichsize(app, handles);
                else
                end
                if get(handles.sizes,'Value') && get(handles.anisotrpy_ellipse,'Value')
                    handles = ellipses_def(app, handles);
                    handles = affichsize_ellipsdef(app, handles);
                end
                    handles = filter(app, handles);
            
                else
                end
            guidata(hObject,handles);
        end

        % Value changed function: editzoom
        function editzoom_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to editzoom (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of editzoom as text
            %        str2double(get(hObject,'String')) returns contents of editzoom as a double
                handles.Param.zoom = str2double(get(hObject, 'String'));
                handles = gaussfilt(app, handles);
                handles = cutfun(app, handles);
                handles = affichcutfft(app, handles);
            
                handles = proporfft(app, handles);
                handles = affichproporfft(app, handles);
                handles = trace_defo(app, handles);
            
                set(handles.zoom,'Value',handles.Param.zoom);
            
                if get(handles.sizes,'Value') && get(handles.anisotrpy_ellipse,'Value')==0
            
                    handles = ellipses_def(app, handles);
                    handles = affichsize(app, handles);
                else
                end
                if get(handles.sizes,'Value') && get(handles.anisotrpy_ellipse,'Value')
                    handles = ellipses_def(app, handles);
                    handles = affichsize_ellipsdef(app, handles);
                end
            
                    if get(handles.adv,'Value')
                        handles = filter(app, handles);
                    else
            
                    end
            
            
            guidata(hObject,handles);
        end

        % Value changed function: enterwindow
        function enterwindow_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to enterwindow (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of enterwindow as text
            %        str2double(get(hObject,'String')) returns contents of enterwindow as a double
                handles.Param.pas2 =  str2double(get(hObject,'String'));
                set(handles.window,'Value',handles.Param.pas2);
                if floor(handles.Param.pas2/2)==handles.Param.pas2/2
                    handles.rec = handles.Param.pas2/2;
                 else
                     handles.rec = (handles.Param.pas2-1)/2;
                end
            
                handles = calculregion(app, handles);
                handles = affichim(app, handles);
                KB=handles.Param.pas2^2/16*9.53674e-7*8;
                KBwhole = KB*handles.regl;
                set(handles.sizesub,'String',sprintf(['One image: ' num2str(floor(KBwhole)) ' MB']))
                handles = affichim(app, handles);
                handles = fftcalculus(app, handles);
                handles = gaussfilt(app, handles);
                handles = cutfun(app, handles);
                handles = affichcutfft(app, handles);
                handles = proporfft(app, handles);
                handles = affichproporfft(app, handles);
                handles = trace_defo(app, handles);
            
                if get(handles.sizes,'Value') && get(handles.anisotrpy_ellipse,'Value')==0
            
                    handles = ellipses_def(app, handles);
                    handles = affichsize(app, handles);
                else
                end
                if get(handles.sizes,'Value') && get(handles.anisotrpy_ellipse,'Value')
                    handles = ellipses_def(app, handles);
                    handles = affichsize_ellipsdef(app, handles);
                end
            
                if handles.adv == 1
                    handles = filter(app, handles);
                else
                    set(get(handles.filt1,'children'),'visible','off')
                    set(get(handles.filt2,'children'),'visible','off')
                    set(get(handles.filt3,'children'),'visible','off')
            
                end


            guidata(hObject, handles);
        end

        % Value changed function: propor
        function propor_Callback(app, event)
            % --- Executes on slider movement.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to propor (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
                handles.Param.propor = get(hObject,'Value');
                handles.Param.propor = handles.Param.propor*10^-3;
                handles = proporfft(app, handles);
                handles = affichproporfft(app, handles);
                handles = trace_defo(app, handles);
                set(handles.editpropor,'String',handles.Param.propor);
                if get(handles.sizes,'Value') && get(handles.anisotrpy_ellipse,'Value')==0
            
                    handles = ellipses_def(app, handles);
                    handles = affichsize(app, handles);
                else
                end
                if get(handles.sizes,'Value') && get(handles.anisotrpy_ellipse,'Value')
                    handles = ellipses_def(app, handles);
                    handles = affichsize_ellipsdef(app, handles);
                end
                if get(handles.adv,'Value')
                handles = filter(app, handles);
                else
                end
            
            guidata(hObject,handles);
        end

        % Value changed function: register
        function register_Callback(app, event)
            % --- Executes on button press in register.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to register (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of register
            handles.Param.register = get(hObject,'Value');
            guidata(hObject, handles);
        end

        % Value changed function: regsize
        function regsize_Callback(app, event)
            % --- Executes on button press in regsize.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to regsize (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of regsize
            handles.Param.regsize = get(hObject,'Value');
            
            guidata(hObject, handles);
        end

        % Value changed function: shuffle
        function shuffle_Callback(app, event)
            % --- Executes on button press in shuffle.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to shuffle (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of shuffle
            shuffleval = get(hObject,'Value');
                if shuffleval == 1
                    set(handles.shuffle,'Value',0);
                    handles = calculregion(app, handles);
                    handles = affichim(app, handles);
                    handles = fftcalculus(app, handles);
                    handles = gaussfilt(app, handles);
                    handles = cutfun(app, handles);
                    handles = affichcutfft(app, handles);
                    handles = proporfft(app, handles);
                    handles = affichproporfft(app, handles);
                    handles = trace_defo(app, handles);
            
                    if get(handles.sizes,'Value') && get(handles.anisotrpy_ellipse,'Value')==0
            
                    handles = ellipses_def(app, handles);
                    handles = affichsize(app, handles);
                else
                end
                if get(handles.sizes,'Value') && get(handles.anisotrpy_ellipse,'Value')
                    handles = ellipses_def(app, handles);
                    handles = affichsize_ellipsdef(app, handles);
                end
            
            
                    if handles.adv == 1
                        handles = filter(app, handles);
                    else
                        set(get(handles.filt1,'children'),'visible','off')
                        set(get(handles.filt2,'children'),'visible','off')
                        set(get(handles.filt3,'children'),'visible','off')
            
                    end
                else
                end
            guidata(hObject, handles);
        end

        % Value changed function: sigma
        function sigma_Callback(app, event)
            % --- Executes on slider movement.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to sigma (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            %newval = floor(get(hObject,'Value'));
                newval = get(hObject,'Value');
             %   handles.sigma = newval;
                handles = fftcalculus(app, handles);
                handles = gaussfilt(app, handles);
                handles = cutfun(app, handles);
                handles = affichcutfft(app, handles);
                set(handles.editsigma,'String',newval);
                handles.Param.sigma = newval;
                if get(handles.sizes,'Value') && get(handles.anisotrpy_ellipse,'Value')==0
            
                    handles = ellipses_def(app, handles);
                    handles = affichsize(app, handles);
                end
                if get(handles.sizes,'Value') && get(handles.anisotrpy_ellipse,'Value')
                    handles = ellipses_def(app, handles);
                    handles = affichsize_ellipsdef(app, handles);
                end
            
               guidata(hObject,handles);
        end

        % Value changed function: sizes
        function sizes_Callback(app, event)
            % --- Executes on button press in sizes.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to sizes (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of sizes
            handles.Param.sizeview = get(hObject,'Value');
            
            
            if get(hObject,'Value')  && get(handles.anisotrpy_ellipse,'Value')==0
                handles = ellipses_def(app, handles);
                handles = affichsize(app, handles);
            end
                if get(hObject,'Value')  && get(handles.anisotrpy_ellipse,'Value')
                    handles = ellipses_def(app, handles);
                    handles = affichsize_ellipsdef(app, handles);
                end
                if get(hObject,'Value') ==0 && get(handles.anisotrpy_ellipse,'Value')==0
                handles = affichcutfft(app, handles);
                handles = trace_defo(app, handles);
                end
            guidata(hObject,handles);
        end

        % Value changed function: slidersize
        function slidersize_Callback(app, event)
            % --- Executes on slider movement.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to slidersize (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            
            handles.Param.nbpoints = get(hObject,'Value');
            set(handles.editsize,'String',handles.Param.nbpoints);
                if get(handles.sizes,'Value') && get(handles.anisotrpy_ellipse,'Value')==0
            
                    handles = ellipses_def(app, handles);
                    handles = affichsize(app, handles);
                else
                end
                if get(handles.sizes,'Value') && get(handles.anisotrpy_ellipse,'Value')
                    handles = ellipses_def(app, handles);
                    handles = affichsize_ellipsdef(app, handles);
                end
                if get(handles.sizes,'Value')==0 && get(handles.anisotrpy_ellipse,'Value')==0
                handles = affichcutfft(app, handles);
                handles = trace_defo(app, handles);
                end
            guidata(hObject, handles);
        end

        % Value changed function: strel
        function strel_Callback(app, event)
            % --- Executes on slider movement.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to strel (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
                newval = floor(get(hObject,'Value'));
                if newval > 4 && newval ~= handles.Param.strel
                    handles.Param.strel = newval;
                    handles = proporfft(app, handles);
                    handles = affichproporfft(app, handles);
                    handles = trace_defo(app, handles);
                    set(handles.editstrel,'String',handles.Param.strel);
                if get(handles.sizes,'Value') && get(handles.anisotrpy_ellipse,'Value')==0
            
                    handles = ellipses_def(app, handles);
                    handles = affichsize(app, handles);
                else
                end
                if get(handles.sizes,'Value') && get(handles.anisotrpy_ellipse,'Value')
                    handles = ellipses_def(app, handles);
                    handles = affichsize_ellipsdef(app, handles);
                end
               handles = filter(app, handles);
            
                else
                end
            guidata(hObject,handles);
        end

        % Value changed function: window
        function window_Callback(app, event)
            % --- Executes on slider movement.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to window (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            
            nl = round(get(hObject,'Value'));
            % n = log(handles.Param.pas2)/log(2);
            % if nl == n
            % else
               % set(hObject,'Value',nl);
            %    handles.Param.pas2 = 2^nl;
            
            %handles.rec = 1/2*handles.Param.pas2;
            
                handles.Param.pas2 = nl;
                if floor(handles.Param.pas2/2)==handles.Param.pas2/2
                    handles.rec = handles.Param.pas2/2;
                 else
                     handles.rec = (handles.Param.pas2-1)/2;
                end
            
                handles = calculregion(app, handles);
                handles = affichim(app, handles);
                KB=handles.Param.pas2^2/16*9.53674e-7*8;
                KBwhole = KB*handles.regl;
                set(handles.sizesub,'String',sprintf(['One image: ' num2str(floor(KBwhole)) ' MB']))
                handles = affichim(app, handles);
                handles = fftcalculus(app, handles);
                handles = gaussfilt(app, handles);
                handles = cutfun(app, handles);
                handles = affichcutfft(app, handles);
                handles = proporfft(app, handles);
                handles = affichproporfft(app, handles);
                handles = trace_defo(app, handles);
                set(handles.enterwindow,'String',handles.Param.pas2);
            
                if get(handles.sizes,'Value') && get(handles.anisotrpy_ellipse,'Value')==0
            
                    handles = ellipses_def(app, handles);
                    handles = affichsize(app, handles);
                else
                end
                if get(handles.sizes,'Value') && get(handles.anisotrpy_ellipse,'Value')
                    handles = ellipses_def(app, handles);
                    handles = affichsize_ellipsdef(app, handles);
                end
            
                if handles.adv == 1
                    handles = filter(app, handles);
                else
                    set(get(handles.filt1,'children'),'visible','off')
                    set(get(handles.filt2,'children'),'visible','off')
                    set(get(handles.filt3,'children'),'visible','off')
            
                end
            %end
            
            guidata(hObject,handles);
        end

        % Value changed function: zoom
        function zoom_Callback(app, event)
            % --- Executes on slider movement.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to zoom (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
                handles.Param.zoom = get(hObject, 'Value');
                handles = gaussfilt(app, handles);
                handles = cutfun(app, handles);
                handles = affichcutfft(app, handles);
            
                handles = proporfft(app, handles);
                handles = affichproporfft(app, handles);
                handles = trace_defo(app, handles);
            
                set(handles.editzoom,'String',handles.Param.zoom);
            
                if get(handles.sizes,'Value') && get(handles.anisotrpy_ellipse,'Value')==0
            
                    handles = ellipses_def(app, handles);
                    handles = affichsize(app, handles);
                else
                end
                if get(handles.sizes,'Value') && get(handles.anisotrpy_ellipse,'Value')
                    handles = ellipses_def(app, handles);
                    handles = affichsize_ellipsdef(app, handles);
                end


                    if get(handles.adv,'Value')
                        handles = filter(app, handles);
                    else
            
                    end
            
            
            guidata(hObject,handles);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'GUI_deformation_ft';
            app.figure1.Tag = 'figure1';
            app.uitoolbar1 = uitoolbar(app.figure1);
            app.uitoolbar1.Tag = 'uitoolbar1';
            app.uipushtool1 = uipushtool(app.uitoolbar1);
            app.uipushtool1.Tag = 'uipushtool1';
            app.uipushtool1.Tooltip = 'Save Figure';
            app.uipushtool1.Icon = 'uipushtool1_image.png';
            app.uitoggletool1 = uitoggletool(app.uitoolbar1);
            app.uitoggletool1.Tag = 'uitoggletool1';
            app.uitoggletool1.Tooltip = 'Pan';
            app.uitoggletool1.Icon = 'uitoggletool1_image.png';
            app.uitoggletool2 = uitoggletool(app.uitoolbar1);
            app.uitoggletool2.Tag = 'uitoggletool2';
            app.uitoggletool2.Tooltip = 'Zoom In';
            app.uitoggletool2.Icon = 'uitoggletool2_image.png';
            app.uitoggletool3 = uitoggletool(app.uitoolbar1);
            app.uitoggletool3.Tag = 'uitoggletool3';
            app.uitoggletool3.Tooltip = 'Zoom Out';
            app.uitoggletool3.Icon = 'uitoggletool3_image.png';
            app.im1 = uiaxes(app.figure1);
            app.im1.XLim = [0.5 256.5];
            app.im1.YLim = [0.5 256.5];
            app.im1.YDir = 'reverse';
            app.im1.Tag = 'im1';
            app.im1.Visible = 'off';
            app.im3 = uiaxes(app.figure1);
            app.im3.XLim = [0.5 256.5];
            app.im3.YLim = [0.5 256.5];
            app.im3.YDir = 'reverse';
            app.im3.Tag = 'im3';
            app.im3.Visible = 'off';
            app.im2 = uiaxes(app.figure1);
            app.im2.XLim = [0.5 256.5];
            app.im2.YLim = [0.5 256.5];
            app.im2.YDir = 'reverse';
            app.im2.Tag = 'im2';
            app.im2.Visible = 'off';
            app.fft1cut = uiaxes(app.figure1);
            app.fft1cut.XLim = [112.5 144.5];
            app.fft1cut.YLim = [112.5 144.5];
            app.fft1cut.YDir = 'reverse';
            app.fft1cut.Tag = 'fft1cut';
            app.fft1cut.Visible = 'off';
            app.fft2cut = uiaxes(app.figure1);
            app.fft2cut.XLim = [112.5 144.5];
            app.fft2cut.YLim = [112.5 144.5];
            app.fft2cut.YDir = 'reverse';
            app.fft2cut.Tag = 'fft2cut';
            app.fft2cut.Visible = 'off';
            app.fft3cut = uiaxes(app.figure1);
            app.fft3cut.XLim = [112.5 144.5];
            app.fft3cut.YLim = [112.5 144.5];
            app.fft3cut.YDir = 'reverse';
            app.fft3cut.Tag = 'fft3cut';
            app.fft3cut.Visible = 'off';
            app.fft1propor = uiaxes(app.figure1);
            app.fft1propor.XLim = [112.5 144.5];
            app.fft1propor.YLim = [112.5 144.5];
            app.fft1propor.YDir = 'reverse';
            app.fft1propor.Tag = 'fft1propor';
            app.fft1propor.Visible = 'off';
            app.fft2propor = uiaxes(app.figure1);
            app.fft2propor.XLim = [112.5 144.5];
            app.fft2propor.YLim = [112.5 144.5];
            app.fft2propor.YDir = 'reverse';
            app.fft2propor.Tag = 'fft2propor';
            app.fft2propor.Visible = 'off';
            app.fft3propor = uiaxes(app.figure1);
            app.fft3propor.XLim = [112.5 144.5];
            app.fft3propor.YLim = [112.5 144.5];
            app.fft3propor.YDir = 'reverse';
            app.fft3propor.Tag = 'fft3propor';
            app.fft3propor.Visible = 'off';
            app.filt1 = uiaxes(app.figure1);
            app.filt1.XLim = [112.5 144.5];
            app.filt1.YLim = [112.5 144.5];
            app.filt1.YDir = 'reverse';
            app.filt1.Tag = 'filt1';
            app.filt1.Visible = 'off';
            app.filt2 = uiaxes(app.figure1);
            app.filt2.XLim = [112.5 144.5];
            app.filt2.YLim = [112.5 144.5];
            app.filt2.YDir = 'reverse';
            app.filt2.Tag = 'filt2';
            app.filt2.Visible = 'off';
            app.filt3 = uiaxes(app.figure1);
            app.filt3.XLim = [112.5 144.5];
            app.filt3.YLim = [112.5 144.5];
            app.filt3.YDir = 'reverse';
            app.filt3.Tag = 'filt3';
            app.filt3.Visible = 'off';
            app.text18 = uilabel(app.figure1);
            app.text18.Tag = 'text18';
            app.text18.Visible = 'off';
            app.text18.Text = 'Size';
            app.cut = uislider(app.figure1);
            app.cut.Limits = [0 30];
            app.cut.MajorTicks = [];
            app.cut.ValueChangedFcn = createCallbackFcn(app, @cut_Callback, true);
            app.cut.MinorTicks = [];
            app.cut.Tag = 'cut';
            app.cut.Value = 1;
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Cut';
            app.propor = uislider(app.figure1);
            app.propor.Limits = [0 200];
            app.propor.MajorTicks = [];
            app.propor.ValueChangedFcn = createCallbackFcn(app, @propor_Callback, true);
            app.propor.MinorTicks = [];
            app.propor.Tag = 'propor';
            app.propor.Value = 10;
            app.text3 = uilabel(app.figure1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Proportion';
            app.OK = uicheckbox(app.figure1);
            app.OK.ValueChangedFcn = createCallbackFcn(app, @OK_Callback, true);
            app.OK.Tag = 'OK';
            app.OK.Text = 'OK';
            app.sigma = uislider(app.figure1);
            app.sigma.Limits = [0 2];
            app.sigma.MajorTicks = [];
            app.sigma.ValueChangedFcn = createCallbackFcn(app, @sigma_Callback, true);
            app.sigma.MinorTicks = [];
            app.sigma.Tag = 'sigma';
            app.sigma.Value = 1;
            app.text5 = uilabel(app.figure1);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Sigma';
            app.strel = uislider(app.figure1);
            app.strel.Limits = [5 10];
            app.strel.MajorTicks = [];
            app.strel.ValueChangedFcn = createCallbackFcn(app, @strel_Callback, true);
            app.strel.MinorTicks = [];
            app.strel.Tag = 'strel';
            app.strel.Visible = 'off';
            app.strel.Value = 5;
            app.pushsigma = uibutton(app.figure1, 'push');
            app.pushsigma.Tag = 'pushsigma';
            app.pushsigma.Visible = 'off';
            app.pushsigma.Text = 'OK';
            app.shuffle = uicheckbox(app.figure1);
            app.shuffle.ValueChangedFcn = createCallbackFcn(app, @shuffle_Callback, true);
            app.shuffle.Tag = 'shuffle';
            app.shuffle.Text = 'Shuffle ';
            app.window = uislider(app.figure1);
            app.window.Limits = [20 1000];
            app.window.MajorTicks = [];
            app.window.ValueChangedFcn = createCallbackFcn(app, @window_Callback, true);
            app.window.MinorTicks = [];
            app.window.Tag = 'window';
            app.window.Value = 100;
            app.text7 = uilabel(app.figure1);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Window';
            app.text8 = uilabel(app.figure1);
            app.text8.Tag = 'text8';
            app.text8.Text = 'Subimage';
            app.text9 = uilabel(app.figure1);
            app.text9.Tag = 'text9';
            app.text9.Text = 'FT + cut + filter';
            app.text10 = uilabel(app.figure1);
            app.text10.Tag = 'text10';
            app.text10.Text = 'Proportion';
            app.text11 = uilabel(app.figure1);
            app.text11.Tag = 'text11';
            app.text11.Text = 'Proportion + strel';
            app.sizes = uicheckbox(app.figure1);
            app.sizes.ValueChangedFcn = createCallbackFcn(app, @sizes_Callback, true);
            app.sizes.Tag = 'sizes';
            app.sizes.Visible = 'off';
            app.sizes.Text = '';
            app.sizesstring = uilabel(app.figure1);
            app.sizesstring.Tag = 'sizesstring';
            app.sizesstring.Text = 'Sizes';
            app.adv = uicheckbox(app.figure1);
            app.adv.ValueChangedFcn = createCallbackFcn(app, @adv_Callback, true);
            app.adv.Tag = 'adv';
            app.adv.Text = 'Advanced parameters: experimented users';
            app.register = uicheckbox(app.figure1);
            app.register.ValueChangedFcn = createCallbackFcn(app, @register_Callback, true);
            app.register.Tag = 'register';
            app.register.Text = 'Save spectrums';
            app.sizesub = uieditfield(app.figure1, 'text');
            app.sizesub.Tag = 'sizesub';
            app.sizesub.Value = 'One image: 143 MB';
            app.choose = uicheckbox(app.figure1);
            app.choose.Tag = 'choose';
            app.choose.Enable = 'off';
            app.choose.Text = 'Choose on image';
            app.regsize = uicheckbox(app.figure1);
            app.regsize.ValueChangedFcn = createCallbackFcn(app, @regsize_Callback, true);
            app.regsize.Tag = 'regsize';
            app.regsize.Visible = 'off';
            app.regsize.Text = 'Save sizes';
            app.num1 = uilabel(app.figure1);
            app.num1.Tag = 'num1';
            app.num1.Text = 'Number: 80';
            app.num2 = uilabel(app.figure1);
            app.num2.Tag = 'num2';
            app.num2.Text = 'Number: 158';
            app.num3 = uilabel(app.figure1);
            app.num3.Tag = 'num3';
            app.num3.Text = 'Number: 276';
            app.slidersize = uislider(app.figure1);
            app.slidersize.Limits = [10 200];
            app.slidersize.MajorTicks = [];
            app.slidersize.ValueChangedFcn = createCallbackFcn(app, @slidersize_Callback, true);
            app.slidersize.MinorTicks = [];
            app.slidersize.Tag = 'slidersize';
            app.slidersize.Visible = 'off';
            app.slidersize.Value = 10;
            app.zoomt = uilabel(app.figure1);
            app.zoomt.Tag = 'zoomt';
            app.zoomt.Text = 'Zoom';
            app.zoom = uislider(app.figure1);
            app.zoom.Limits = [0 20];
            app.zoom.MajorTicks = [];
            app.zoom.ValueChangedFcn = createCallbackFcn(app, @zoom_Callback, true);
            app.zoom.MinorTicks = [];
            app.zoom.Tag = 'zoom';
            app.zoom.Value = 8;
            app.choose_1 = uicheckbox(app.figure1);
            app.choose_1.ValueChangedFcn = createCallbackFcn(app, @choose_Callback, true);
            app.choose_1.Tag = 'choose_1';
            app.choose_1.Text = 'Choose on image ';
            app.textstrel = uilabel(app.figure1);
            app.textstrel.Tag = 'textstrel';
            app.textstrel.Visible = 'off';
            app.textstrel.Text = 'Disk strel';
            app.enterwindow = uieditfield(app.figure1, 'text');
            app.enterwindow.ValueChangedFcn = createCallbackFcn(app, @enterwindow_Callback, true);
            app.enterwindow.Tag = 'enterwindow';
            app.enterwindow.Value = 'Edit size';
            app.editsigma = uieditfield(app.figure1, 'text');
            app.editsigma.ValueChangedFcn = createCallbackFcn(app, @editsigma_Callback, true);
            app.editsigma.Tag = 'editsigma';
            app.editsigma.Value = 'Edit sigma';
            app.editcut = uieditfield(app.figure1, 'text');
            app.editcut.ValueChangedFcn = createCallbackFcn(app, @editcut_Callback, true);
            app.editcut.Tag = 'editcut';
            app.editcut.Value = 'Edit cut';
            app.editpropor = uieditfield(app.figure1, 'text');
            app.editpropor.ValueChangedFcn = createCallbackFcn(app, @editpropor_Callback, true);
            app.editpropor.Tag = 'editpropor';
            app.editpropor.Value = 'Edit propor';
            app.editstrel = uieditfield(app.figure1, 'text');
            app.editstrel.ValueChangedFcn = createCallbackFcn(app, @editstrel_Callback, true);
            app.editstrel.Tag = 'editstrel';
            app.editstrel.Visible = 'off';
            app.editstrel.Value = 'Edit Strel';
            app.editsize = uieditfield(app.figure1, 'text');
            app.editsize.ValueChangedFcn = createCallbackFcn(app, @editsize_Callback, true);
            app.editsize.Tag = 'editsize';
            app.editsize.Visible = 'off';
            app.editsize.Value = 'Edit Size';
            app.editzoom = uieditfield(app.figure1, 'text');
            app.editzoom.ValueChangedFcn = createCallbackFcn(app, @editzoom_Callback, true);
            app.editzoom.Tag = 'editzoom';
            app.editzoom.Value = 'Edit Zoom';
            app.anisotrpy_ellipse = uicheckbox(app.figure1);
            app.anisotrpy_ellipse.ValueChangedFcn = createCallbackFcn(app, @anisotrpy_ellipse_Callback, true);
            app.anisotrpy_ellipse.Tag = 'anisotrpy_ellipse';
            app.anisotrpy_ellipse.Visible = 'off';
            app.anisotrpy_ellipse.Text = '';
            app.nameani_ellipse = uieditfield(app.figure1, 'text');
            app.nameani_ellipse.Tag = 'nameani_ellipse';
            app.nameani_ellipse.Visible = 'off';
            app.nameani_ellipse.Value = 'Anisotropy from ellipse fit';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_deformation_ft_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)GUI_deformation_ft_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end