classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        zoomSlider                matlab.ui.control.Slider
        zoomEditField             matlab.ui.control.NumericEditField
        zoomEditFieldLabel        matlab.ui.control.Label
        chooseButton              matlab.ui.control.Button
        shuffleButton             matlab.ui.control.Button
        UIAxesfilt3               matlab.ui.control.UIAxes
        UIAxesfilt2               matlab.ui.control.UIAxes
        UIAxesfilt1               matlab.ui.control.UIAxes
        UIAxesIm3bin              matlab.ui.control.UIAxes
        UIAxesIm2bin              matlab.ui.control.UIAxes
        UIAxesIm1bin              matlab.ui.control.UIAxes
        UIAxesIm3FT               matlab.ui.control.UIAxes
        UIAxesIm2FT               matlab.ui.control.UIAxes
        UIAxesIm3                 matlab.ui.control.UIAxes
        UIAxesIm2                 matlab.ui.control.UIAxes
        UIAxesIm1FT               matlab.ui.control.UIAxes
        UIAxesIm1                 matlab.ui.control.UIAxes
        RightPanel                matlab.ui.container.Panel
        adv                       matlab.ui.control.CheckBox
        sizes                     matlab.ui.control.CheckBox
        sizesub                   matlab.ui.control.Label
        regionsizeSlider          matlab.ui.control.Slider
        regionsizeEditField       matlab.ui.control.NumericEditField
        regionsizeEditFieldLabel  matlab.ui.control.Label
        cutEditField              matlab.ui.control.NumericEditField
        cutEditFieldLabel         matlab.ui.control.Label
        cutSlider                 matlab.ui.control.Slider
        Label_2                   matlab.ui.control.Label
        sigmaSlider               matlab.ui.control.Slider
        Label                     matlab.ui.control.Label
        sigmaEditField            matlab.ui.control.NumericEditField
        sigmaEditFieldLabel       matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        Param; % Collection of parameters, initial values passed in
        Anamesi; %input directory, as given by cleanfolder() 
        image1;
        image2;
        image3; %indices of subimages to display?
        Posi; %list of positions of subimages
        regl; %indices of possible subimages?
        num1;
        num2;
        num3; %String indicating indices of subimage displayed? app 
        % text label component above?
        sub1;
        sub2;
        sub3; %data in subwindows 1,2,3
        wins; %list of currently displayed subfigure indices
        fftsub1;
        fftsub2;
        fftsub3; %Fourier transformed subimages
        spsub1; 
        spsub2;
        spsub3; %gaussian filtered Fourier transformed subimages
        fftsubcut1;
        fftsubcut2;
        fftsubcut3; %cut and filtered FT
        fftsubcutsp1;
        fftsubcutsp2;
        fftsubcutsp3;
        M1;
        M2;
        M3;
        fftsubcutspse1;
        fftsubcutspse2;
        fftsubcutspse3; %after morphological operation
    end

    methods (Access = private)
        %migrate GUIDE functions "handles = f(handles)"
        %to functions "f(app)" which changes app's properties
        
        function calculregion(app) 
            [app.Posi,app.regl] = Position_gui(app.Param);
        end

        function affichim(app) 
            %choose image from folder (timepoints)
            a = choose_image(app.Param.name{1},app.Anamesi,app.Param.tsart);

            if app.image1 ~=0 %subimages already chosen
                win1 = app.image1;
                win2 = app.image2;
                win3 = app.image3;
            elseif app.regl == 1 %only one subimage available
                win1 = 1;
                win2 = 1;
                win3 = 1;
            else %TODO could be random int
                win1 = round(rand(1)*app.regl);
                win2 = round(rand(1)*app.regl);
                win3 = round(rand(1)*app.regl);

            end

            set(app.num1,'String',['Number: ' num2str(win1)]);
            set(app.num2,'String',['Number: ' num2str(win2)]);
            set(app.num3,'String',['Number: ' num2str(win3)]);


            %cut subimage's data out of image and display
            x = app.Posi(win1,1);
            y = app.Posi(win1,2);
            app.sub1=a(y:y+app.Param.subwindow_size-1,x:x+app.Param.subwindow_size-1);
            imagesc(app.sub1,Parent=app.UIAxesIm1)
            set(app.UIAxesIm1,'Visible','off') %I don't know why, also seems
            %to have no effect

            x = app.Posi(win2,1);
            y = app.Posi(win2,2);
            app.sub2=a(y:y+app.Param.subwindow_size-1,x:x+app.Param.subwindow_size-1);
            imagesc(app.sub2,'Parent',app.UIAxesIm2)
            set(app.UIAxesIm2,'Visible','off')

            x = app.Posi(win3,1);
            y = app.Posi(win3,2);
            app.sub3=a(y:y+app.Param.subwindow_size-1,x:x+app.Param.subwindow_size-1);
            imagesc(app.sub3,'Parent',app.UIAxesIm3)
            set(app.UIAxesIm3,'Visible','off')

            app.wins = [win1,win2,win3];
        end

        function fftcalculus(app)
            % asubs contains the subimages we will use
            FTs = avfft(app.Param.name,app.wins,app.Param,app.Posi);
            app.fftsub1 = reshape(FTs(1,:,:),app.Param.subwindow_size,app.Param.subwindow_size);
            app.fftsub2 = reshape(FTs(2,:,:),app.Param.subwindow_size,app.Param.subwindow_size);
            app.fftsub3 = reshape(FTs(3,:,:),app.Param.subwindow_size,app.Param.subwindow_size);
        end

        function gaussfilt(app)
                filter = gaussianFilter(ceil(2*app.Param.sigma),app.Param.sigma);
                app.spsub1 = filter2(filter,app.fftsub1);
                app.spsub2 = filter2(filter,app.fftsub2);
                app.spsub3 = filter2(filter,app.fftsub3);
        end

        function cutfun(app)
            app.fftsubcut1 = app.spsub1;
            app.fftsubcut2 = app.spsub2;
            app.fftsubcut3 = app.spsub3;
            ic = app.Param.subwindow_size*1/2+1;
            jc = app.Param.subwindow_size*1/2+1;
            [I,J] = ndgrid(1:app.Param.subwindow_size,1:app.Param.subwindow_size);
            M = double((I-ic).^2+(J-jc).^2<=(app.Param.cut)^2);
            app.fftsubcut1(M>0) = 0;
            app.fftsubcut2(M>0) = 0;
            app.fftsubcut3(M>0) = 0;
        end

        function affichcutfft(app)
            %display the cut and filtered fourier transform images
            imagesc(app.fftsubcut1,Parent=app.UIAxesIm1FT)
            % TODO the zoom twitch is not ideal
            zoom(app.UIAxesIm1FT,'out')
            zoom(app.UIAxesIm1FT,app.Param.zoom)
            set(app.UIAxesIm1FT,Visible='off')

            imagesc(app.fftsubcut2,Parent=app.UIAxesIm2FT)
            zoom(app.UIAxesIm2FT,'out')
            zoom(app.UIAxesIm2FT,app.Param.zoom)
            set(app.UIAxesIm2FT,Visible='off')

            imagesc(app.fftsubcut3,Parent=app.UIAxesIm3FT)
            axes(app.UIAxesIm3FT);
            zoom(app.UIAxesIm3FT,'out')
            zoom(app.UIAxesIm3FT,app.Param.zoom)
            set(app.UIAxesIm3FT,Visible='off')
        end

        function proporfft(app)
            ic = app.Param.subwindow_size/2+1;
            jc = app.Param.subwindow_size/2+1;
            [I,J] = ndgrid(1:app.Param.subwindow_size,1:app.Param.subwindow_size);
            %mask by distance from center
            M = double((I-ic).^2+(J-jc).^2<=(app.Param.cut)^2);

            app.fftsubcutsp1 = app.fftsubcut1;
            app.fftsubcutsp2 = app.fftsubcut2;
            app.fftsubcutsp3 = app.fftsubcut3;

            reshaped = reshape(app.fftsubcutsp1,[app.Param.subwindow_size*app.Param.subwindow_size ,1]);         
            % Colonnes par colonnes donc l'indexage en
            [~,index]= sort(reshaped ,'descend');
            nbel = app.Param.propor * app.Param.subwindow_size*app.Param.subwindow_size;
            if nbel > app.Param.subwindow_size*app.Param.subwindow_size
                nbel =  app.Param.subwindow_size*app.Param.subwindow_size;
            else
            end

            app.fftsubcutsp1(index(1:floor(nbel))) = 1;
            app.fftsubcutsp1(index(floor(nbel)+1:end)) = 0;

            app.fftsubcutsp1(M>0) = 1;

            spsubcutfilt = app.fftsubcutsp1;
            B = false(app.Param.subwindow_size);
            B(spsubcutfilt>0)=1;
            se=strel('disk',app.Param.strel);
            %TODO should it be open?
            app.fftsubcutspse1=imclose(B,se);
            spsubcutfilt =  app.fftsubcutspse1;

            norm = sum(sum((spsubcutfilt).^2));
            [Yg,Xg]=meshgrid(1:(app.Param.subwindow_size),1:(app.Param.subwindow_size));
            xc = app.Param.subwindow_size/2;
            yc = app.Param.subwindow_size/2;
            Xo = Xg-xc;
            Yo = Yg-yc;
            Y = (Yg-yc).^2; % Lignes
            X = (Xg-xc).^2; % Colonnes
            YY = sum(sum((spsubcutfilt).^2.*Y))/norm;
            XX = sum(sum((spsubcutfilt).^2.*X))/norm;
            XY = sum(sum((spsubcutfilt).^2.*Xo.*Yo))/norm;
            app.M1 = [XX,XY;XY,YY];

            %-------------------
            %repeat for 2,3

            reshaped = reshape(app.fftsubcutsp2,[app.Param.subwindow_size*app.Param.subwindow_size ,1]);         
            % Colonnes par colonnes donc l'indexage en
            [~,index]= sort(reshaped ,'descend');
            nbel = app.Param.propor * app.Param.subwindow_size*app.Param.subwindow_size;
            if nbel > app.Param.subwindow_size*app.Param.subwindow_size
                nbel =  app.Param.subwindow_size*app.Param.subwindow_size;
            else
            end

            app.fftsubcutsp2(index(1:floor(nbel))) = 1;
            app.fftsubcutsp2(index(floor(nbel)+1:end)) = 0;
            app.fftsubcutsp2(M>0) = 1;

            spsubcutfilt = app.fftsubcutsp2;

            B = false(app.Param.subwindow_size);
            B(spsubcutfilt>0)=1;
            se=strel('disk',app.Param.strel);
            app.fftsubcutspse2=imclose(B,se);
            spsubcutfilt =  app.fftsubcutspse2;

            norm = sum(sum((spsubcutfilt).^2));
            [Yg,Xg]=meshgrid(1:(app.Param.subwindow_size),1:(app.Param.subwindow_size));
            xc = app.Param.subwindow_size/2;
            yc = app.Param.subwindow_size/2;
            Xo = Xg-xc;
            Yo = Yg-yc;
            Y = (Yg-yc).^2; % Lignes
            X = (Xg-xc).^2; % Colonnes
            YY = sum(sum((spsubcutfilt).^2.*Y))/norm;
            XX = sum(sum((spsubcutfilt).^2.*X))/norm;
            XY = sum(sum((spsubcutfilt).^2.*Xo.*Yo))/norm;
            app.M2 = [XX,XY;XY,YY];

            reshaped = reshape(app.fftsubcutsp3,[app.Param.subwindow_size*app.Param.subwindow_size,1]);         % Colonnes par colonnes donc l'indexage en
            [~,index]= sort(reshaped ,'descend');
            nbel = app.Param.propor * app.Param.subwindow_size*app.Param.subwindow_size;
            if nbel > app.Param.subwindow_size*app.Param.subwindow_size
                nbel =  app.Param.subwindow_size*app.Param.subwindow_size;
            else
            end

            app.fftsubcutsp3(index(1:floor(nbel))) = 1;
            app.fftsubcutsp3(index(floor(nbel)+1:end)) = 0;
            app.fftsubcutsp3(M>0) = 1;

            spsubcutfilt = app.fftsubcutsp3;
            B = false(app.Param.subwindow_size);
            B(spsubcutfilt>0)=1;
            se=strel('disk',app.Param.strel);
            app.fftsubcutspse3=imclose(B,se);
            spsubcutfilt =  app.fftsubcutspse3;

            norm = sum(sum((spsubcutfilt).^2));
            [Yg,Xg]=meshgrid(1:(app.Param.subwindow_size),1:(app.Param.subwindow_size));
            xc = app.Param.subwindow_size/2;
            yc = app.Param.subwindow_size/2;
            Xo = Xg-xc;
            Yo = Yg-yc;
            Y = (Yg-yc).^2; % Lignes
            X = (Xg-xc).^2; % Colonnes
            YY = sum(sum((spsubcutfilt).^2.*Y))/norm;
            XX = sum(sum((spsubcutfilt).^2.*X))/norm;
            XY = sum(sum((spsubcutfilt).^2.*Xo.*Yo))/norm;
            app.M3 = [XX,XY;XY,YY];
        end

        function affichproporfft(app)

            imagesc(app.fftsubcutsp1,Parent=app.UIAxesIm1bin)
            zoom(app.UIAxesIm1bin,'out')
            zoom(app.UIAxesIm1bin,app.Param.zoom)
            set(app.UIAxesIm1bin,Visible='off')

            imagesc(app.fftsubcutsp2,Parent=app.UIAxesIm2bin)
            zoom(app.UIAxesIm2bin,'out')
            zoom(app.UIAxesIm2bin,app.Param.zoom)
            set(app.UIAxesIm2bin,Visible='off')

            imagesc(app.fftsubcutsp3,Parent=app.UIAxesIm3bin)
            zoom(app.UIAxesIm3bin,'out')
            zoom(app.UIAxesIm3bin,app.Param.zoom)
            set(app.UIAxesIm3bin,Visible='off')
        end

        function filter(app)
            imagesc(app.fftsubcutspse1,Parent=app.UIAxesfilt1);
            zoom(app.UIAxesfilt1,app.Param.zoom)
            set(app.UIAxesfilt1,Visible='off');

            imagesc(app.fftsubcutspse2,Parent=app.UIAxesfilt2);
            zoom(app.UIAxesfilt2,app.Param.zoom)
            set(app.UIAxesfilt2,Visible='off');

            imagesc(app.fftsubcutspse3,Parent=app.UIAxesfilt3);
            zoom(app.UIAxesfilt3,app.Param.zoom)
            set(app.UIAxesfilt3,Visible='off');
        end

        function trace_defo(app)

            xo =  app.Param.subwindow_size/2;
            yo =  app.Param.subwindow_size/2;

            [~,E] = eig(app.M1);
            L1 = 2*sqrt(E(2,2)/2);
            L2 = 2*sqrt(E(1,1)/2);
            lgE = -1/2*(log(L1/L2));
            Dev = app.M1-1/2*trace(app.M1)*eye(2);
            [V,~] = eig(Dev);
            ang=atan(V(1,2)/V(2,2));
            angS = ang;
            S = lgE;

            amp = S;
            ang = angS;

            x1 = xo+cos(ang+pi/2)*amp*app.Param.scale;
            y1 = yo+sin(ang+pi/2)*amp*app.Param.scale;
            x2 = xo-cos(ang+pi/2)*amp*app.Param.scale;
            y2 = yo-sin(ang+pi/2)*amp*app.Param.scale;

            X1 = [xo,x1];
            Y1 = [yo,y1];
            X2 = [xo,x2];
            Y2 = [yo,y2];

            imagesc(app.sub1,'Parent',app.UIAxesIm1)
            hold(app.UIAxesIm1,'on');
            plot(X1,Y1,'Marker','.','LineStyle','-','Color','red','LineWidth',3,'Parent',app.UIAxesIm1);
            plot(X2,Y2,'Marker','.','LineStyle','-','Color','red','LineWidth',3,'Parent',app.UIAxesIm1);
            set(app.UIAxesIm1,'Visible','off')
            hold(app.UIAxesIm1,'off');


            [~,E] = eig(app.M2);
            L1 = 2*sqrt(E(2,2)/2);
            L2 = 2*sqrt(E(1,1)/2);
            lgE = -1/2*(log(L1/L2));
            Dev = app.M2-1/2*trace(app.M2)*eye(2);
            [V,~] = eig(Dev);
            ang=atan(V(1,2)/V(2,2));
            angS = ang;
            S = lgE;

            amp = S;
            ang = angS;

            x1 = xo+cos(ang+pi/2)*amp*app.Param.scale;
            y1 = yo+sin(ang+pi/2)*amp*app.Param.scale;
            x2 = xo-cos(ang+pi/2)*amp*app.Param.scale;
            y2 = yo-sin(ang+pi/2)*amp*app.Param.scale;

            X1 = [xo,x1];
            Y1 = [yo,y1];
            X2 = [xo,x2];
            Y2 = [yo,y2];

            imagesc(app.sub2,'Parent',app.UIAxesIm2)
            hold(app.UIAxesIm2,'on');
            plot(X1,Y1,'Marker','.','LineStyle','-','Color','red','LineWidth',3,'Parent',app.UIAxesIm2);
            plot(X2,Y2,'Marker','.','LineStyle','-','Color','red','LineWidth',3,'Parent',app.UIAxesIm2);
            set(app.UIAxesIm2,'Visible','off')
            hold(app.UIAxesIm2,'off');


            [~,E] = eig(app.M3);
            L1 = 2*sqrt(E(2,2)/2);
            L2 = 2*sqrt(E(1,1)/2);
            lgE = -1/2*(log(L1/L2));
            Dev = app.M3-1/2*trace(app.M3)*eye(2);
            [V,~] = eig(Dev);
            ang=atan(V(1,2)/V(2,2));
            angS = ang;
            S = lgE;

            amp = S;
            ang = angS;

            x1 = xo+cos(ang+pi/2)*amp*app.Param.scale;
            y1 = yo+sin(ang+pi/2)*amp*app.Param.scale;
            x2 = xo-cos(ang+pi/2)*amp*app.Param.scale;
            y2 = yo-sin(ang+pi/2)*amp*app.Param.scale;

            X1 = [xo,x1];
            Y1 = [yo,y1];
            X2 = [xo,x2];
            Y2 = [yo,y2];

            imagesc(app.sub3,'Parent',app.UIAxesIm3)
            hold(app.UIAxesIm3,'on');
            plot(X1,Y1,'Marker','.','LineStyle','-','Color','red','LineWidth',3,'Parent',app.UIAxesIm3);
            plot(X2,Y2,'Marker','.','LineStyle','-','Color','red','LineWidth',3,'Parent',app.UIAxesIm3);
            set(app.UIAxesIm3,'Visible','off')
            hold(app.UIAxesIm3,'off');
        end
        
        function change_regionsize(app)
            %redoes everything after subwindow size has been changed;
            %used by both slider and edit field

            %reset cut limits
            app.cutEditField.Limits = [0 app.Param.subwindow_size/2];
            app.cutSlider.Limits = [0 app.Param.subwindow_size/2];

            calculregion(app);
            affichim(app);
            KB=app.Param.subwindow_size^2/16*9.53674e-7*8;
            KBwhole = KB*app.regl;
            app.sizesub.Text=sprintf(['One image: ' num2str(floor(KBwhole)) ' MB']);
            affichim(app);
            fftcalculus(app);
            gaussfilt(app);
            cutfun(app);
            affichcutfft(app);
            proporfft(app);
            affichproporfft(app);
            trace_defo(app);

            if app.sizes.Value && app.anisotrpy_ellipse.Value==0
                ellipses_def(app);
                affichsize(app);
            else
            end
            if app.sizes.Value && app.anisotrpy_ellipse.Value
                ellipses_def(app);
                affichsize_ellipsdef(app);
            end

            if app.adv == 1
                filter(app);
            else
                set(get(app.UIAxesfilt1,'children'),'visible','off')
                set(get(app.UIAxesfilt2,'children'),'visible','off')
                set(get(app.UIAxesfilt3,'children'),'visible','off')

            end
        end

        function changeSigmaOrCut(app)
            %everything to redo when sigma or cut changes,
            %used by sigma slider and edit field
            fftcalculus(app);
            gaussfilt(app);
            cutfun(app);
            affichcutfft(app);

            proporfft(app);
            affichproporfft(app);
            filter(app);
            trace_defo(app);

            if app.sizes.Value && app.anisotrpy_ellipse.Value
                ellipses_def(app);
                affichsize(app);
            end
            if app.sizes.Value && app.anisotrpy_ellipse.Value
                ellipses_def(app);
                affichsize_ellipsdef(app);
            end
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            %save initial settings as app properties 
            app.Param=varargin{1};

            app.Anamesi = cleanfolder([app.Param.name{1}]);
            app.Param.siz = size_image(app.Param.name{1},app.Anamesi);

            %decide range of edit fields and sliders based on image size
            maxsubwindow = min(500, max(app.Param.siz)/8); %arbitrary limit
            app.regionsizeEditField.Limits = [1 maxsubwindow];
            app.regionsizeSlider.Limits = [1 maxsubwindow];
            app.cutEditField.Limits = [0 app.Param.subwindow_size/2];
            app.cutSlider.Limits = [0 app.Param.subwindow_size/2];
            %display initial values in edit fields
            app.regionsizeEditField.Value = app.Param.subwindow_size;
            app.sigmaEditField.Value = app.Param.sigma;
            app.cutEditField.Value = app.Param.cut;
            app.zoomEditField.Value = app.Param.zoom;
            %and sliders
            app.regionsizeSlider.Value = app.Param.subwindow_size;
            app.sigmaSlider.Value = app.Param.sigma;
            app.cutSlider.Value = app.Param.cut;

            %display 3 random tiles
            calculregion(app) %determines possible subimage locations
            affichim(app) %chooses subimages and displays

            %calculate FT 
            % blur and cut with initial values sigma, cut
            fftcalculus(app);
            gaussfilt(app);
            cutfun(app);

            % display initial FT plots - where to store these?  A property
            % of app, or an external object(?) previously in...
            affichcutfft(app);
            %display initial binarized plots
            proporfft(app);
            affichproporfft(app);
            filter(app);

            %the orientation line and ellipse?
            trace_defo(app);

            %the fourth column of images non-visible until turned on
            set(get(app.UIAxesfilt1,'children'),'visible','off')
            set(get(app.UIAxesfilt2,'children'),'visible','off')
            set(get(app.UIAxesfilt3,'children'),'visible','off')

            %??
            KB=app.Param.subwindow_size^2*9.53674e-7*8;
            KBwhole  = KB*app.regl;
            %a a text label
            app.sizesub.Text=sprintf(['One image: ' num2str(floor(KBwhole)) ' MB']);
            % Update handles structure
            %guidata(hObject, handles);
            
            %and possible return something, if needed
            %varargout{1} = handles;

        end

        % Callback function
        function ButtonPushed(app, event)

        end

        % Value changed function: regionsizeEditField
        function editSubimageSizeCallback(app, event)
            app.Param.subwindow_size = app.regionsizeEditField.Value;

            %share with slider
            app.regionsizeSlider.Value = app.Param.subwindow_size;
            %recalculate and re-display everything
            change_regionsize(app)
            
        end

        % Value changed function: regionsizeSlider
        function sliderSubimageSizeCallback(app, event)
            app.Param.subwindow_size = round(app.regionsizeSlider.Value);

            %share with edit field
            app.regionsizeEditField.Value = app.Param.subwindow_size;
            %recalculate and re-display everything
            change_regionsize(app)
            
        end

        % Value changed function: sigmaEditField
        function SigmaEditFieldCallback(app, event)
            app.Param.sigma = app.sigmaEditField.Value;
            %share with slider
            app.sigmaSlider.Value = app.Param.sigma;
            %recalculate and re-display relevant parts
            changeSigmaOrCut(app)
            
        end

        % Value changed function: sigmaSlider
        function SigmaSliderCallback(app, event)
            app.Param.sigma = app.sigmaSlider.Value;
            %share with edit field
            app.sigmaEditField.Value = app.Param.sigma;
            %recalculate and re-display relevant parts
            changeSigmaOrCut(app)
            
        end

        % Value changed function: cutSlider
        function cutSliderCallback(app, event)
            app.Param.cut = app.cutSlider.Value;
            %share with edit field
            app.cutEditField.Value = app.Param.cut;
            %recalculate and re-display relevant parts
            changeSigmaOrCut(app)
        end

        % Value changed function: cutEditField
        function cutEditFieldCallback(app, event)
            app.Param.cut = app.cutEditField.Value;
            %share with Slider
            app.cutSlider.Value = app.Param.cut;
            %recalculate and re-display relevant parts
            changeSigmaOrCut(app)
        end

        % Button pushed function: shuffleButton
        function shuffleCallback(app, event)
            calculregion(app);
            affichim(app);
            fftcalculus(app);
            gaussfilt(app);
            cutfun(app);
            affichcutfft(app);
            affichproporfft(app);
            trace_defo(app);

            if app.sizes.Value && app.anisotrpy_ellipse.Value==0

                ellipses_def(app);
                affichsize(app);
            end
            if app.sizes.Value && app.anisotrpy_ellipse.Value
                ellipses_def(app);
                affichsize_ellipsdef(app);
            end


            if app.adv == 1
                filter(app);
            else
                set(get(app.UIAxesfilt1,'children'),'visible','off');
                set(get(app.UIAxesfilt2,'children'),'visible','off');
                set(get(app.UIAxesfilt3,'children'),'visible','off');

            end
       
        end

        % Value changed function: zoomEditField
        function zoomEditFieldCallback(app, event)
            app.Param.zoom = app.zoomEditField.Value;
            app.zoomSlider.Value = app.Param.zoom;
            %redisplay last 2 or 3 columns
        end

        % Value changed function: zoomSlider
        function zoomSliderCallback(app, event)
            app.Param.zoom = app.zoomSlider.Value;
            app.zoomEditField.Value = app.Param.zoom;
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {415, 415};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {590, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.UIAxesIm1 = uiaxes(app.LeftPanel);
            app.UIAxesIm1.XLimitMethod = 'tight';
            app.UIAxesIm1.YLimitMethod = 'tight';
            app.UIAxesIm1.ZLimitMethod = 'tight';
            app.UIAxesIm1.XTick = [];
            app.UIAxesIm1.YTick = [];
            app.UIAxesIm1.ZTick = [];
            app.UIAxesIm1FT = uiaxes(app.LeftPanel);
            app.UIAxesIm1FT.XLimitMethod = 'tight';
            app.UIAxesIm1FT.YLimitMethod = 'tight';
            app.UIAxesIm1FT.ZLimitMethod = 'tight';
            app.UIAxesIm1FT.XTick = [];
            app.UIAxesIm1FT.YTick = [];
            app.UIAxesIm1FT.ZTick = [];
            app.UIAxesIm2 = uiaxes(app.LeftPanel);
            app.UIAxesIm2.XLimitMethod = 'tight';
            app.UIAxesIm2.YLimitMethod = 'tight';
            app.UIAxesIm2.ZLimitMethod = 'tight';
            app.UIAxesIm2.XTick = [];
            app.UIAxesIm2.YTick = [];
            app.UIAxesIm2.ZTick = [];
            app.UIAxesIm3 = uiaxes(app.LeftPanel);
            app.UIAxesIm3.XLimitMethod = 'tight';
            app.UIAxesIm3.YLimitMethod = 'tight';
            app.UIAxesIm3.ZLimitMethod = 'tight';
            app.UIAxesIm3.XTick = [];
            app.UIAxesIm3.YTick = [];
            app.UIAxesIm3.ZTick = [];
            app.UIAxesIm2FT = uiaxes(app.LeftPanel);
            app.UIAxesIm2FT.XLimitMethod = 'tight';
            app.UIAxesIm2FT.YLimitMethod = 'tight';
            app.UIAxesIm2FT.ZLimitMethod = 'tight';
            app.UIAxesIm2FT.XTick = [];
            app.UIAxesIm2FT.YTick = [];
            app.UIAxesIm2FT.ZTick = [];
            app.UIAxesIm2FT.Visible = 'off';
            app.UIAxesIm3FT = uiaxes(app.LeftPanel);
            app.UIAxesIm3FT.XLimitMethod = 'tight';
            app.UIAxesIm3FT.YLimitMethod = 'tight';
            app.UIAxesIm3FT.ZLimitMethod = 'tight';
            app.UIAxesIm3FT.XTick = [];
            app.UIAxesIm3FT.YTick = [];
            app.UIAxesIm3FT.ZTick = [];
            app.UIAxesIm1bin = uiaxes(app.LeftPanel);
            app.UIAxesIm1bin.XLimitMethod = 'tight';
            app.UIAxesIm1bin.YLimitMethod = 'tight';
            app.UIAxesIm1bin.ZLimitMethod = 'tight';
            app.UIAxesIm1bin.XTick = [];
            app.UIAxesIm1bin.YTick = [];
            app.UIAxesIm1bin.ZTick = [];
            app.UIAxesIm2bin = uiaxes(app.LeftPanel);
            app.UIAxesIm2bin.XLimitMethod = 'tight';
            app.UIAxesIm2bin.YLimitMethod = 'tight';
            app.UIAxesIm2bin.ZLimitMethod = 'tight';
            app.UIAxesIm2bin.XTick = [];
            app.UIAxesIm2bin.YTick = [];
            app.UIAxesIm2bin.ZTick = [];
            app.UIAxesIm3bin = uiaxes(app.LeftPanel);
            app.UIAxesIm3bin.XLimitMethod = 'tight';
            app.UIAxesIm3bin.YLimitMethod = 'tight';
            app.UIAxesIm3bin.ZLimitMethod = 'tight';
            app.UIAxesIm3bin.XTick = [];
            app.UIAxesIm3bin.YTick = [];
            app.UIAxesIm3bin.ZTick = [];
            app.UIAxesfilt1 = uiaxes(app.LeftPanel);
            app.UIAxesfilt1.XLimitMethod = 'tight';
            app.UIAxesfilt1.YLimitMethod = 'tight';
            app.UIAxesfilt1.ZLimitMethod = 'tight';
            app.UIAxesfilt1.XTick = [];
            app.UIAxesfilt1.YTick = [];
            app.UIAxesfilt1.ZTick = [];
            app.UIAxesfilt2 = uiaxes(app.LeftPanel);
            app.UIAxesfilt2.XLimitMethod = 'tight';
            app.UIAxesfilt2.YLimitMethod = 'tight';
            app.UIAxesfilt2.ZLimitMethod = 'tight';
            app.UIAxesfilt2.XTick = [];
            app.UIAxesfilt2.YTick = [];
            app.UIAxesfilt2.ZTick = [];
            app.UIAxesfilt3 = uiaxes(app.LeftPanel);
            app.UIAxesfilt3.XLimitMethod = 'tight';
            app.UIAxesfilt3.YLimitMethod = 'tight';
            app.UIAxesfilt3.ZLimitMethod = 'tight';
            app.UIAxesfilt3.XTick = [];
            app.UIAxesfilt3.YTick = [];
            app.UIAxesfilt3.ZTick = [];
            app.shuffleButton = uibutton(app.LeftPanel, 'push');
            app.shuffleButton.ButtonPushedFcn = createCallbackFcn(app, @shuffleCallback, true);
            app.shuffleButton.Text = 'shuffle';
            app.chooseButton = uibutton(app.LeftPanel, 'push');
            app.chooseButton.Text = 'choose';
            app.zoomEditFieldLabel = uilabel(app.LeftPanel);
            app.zoomEditFieldLabel.Text = 'zoom';
            app.zoomEditField = uieditfield(app.LeftPanel, 'numeric');
            app.zoomEditField.Limits = [0 10];
            app.zoomEditField.ValueChangedFcn = createCallbackFcn(app, @zoomEditFieldCallback, true);
            app.zoomSlider = uislider(app.LeftPanel);
            app.zoomSlider.Limits = [0 10];
            app.zoomSlider.ValueChangedFcn = createCallbackFcn(app, @zoomSliderCallback, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.sigmaEditFieldLabel = uilabel(app.RightPanel);
            app.sigmaEditFieldLabel.Text = 'sigma';
            app.sigmaEditField = uieditfield(app.RightPanel, 'numeric');
            app.sigmaEditField.Limits = [0 1];
            app.sigmaEditField.ValueChangedFcn = createCallbackFcn(app, @SigmaEditFieldCallback, true);
            app.Label = uilabel(app.RightPanel);
            app.Label.Text = '';
            app.sigmaSlider = uislider(app.RightPanel);
            app.sigmaSlider.Limits = [0 1];
            app.sigmaSlider.ValueChangedFcn = createCallbackFcn(app, @SigmaSliderCallback, true);
            app.Label_2 = uilabel(app.RightPanel);
            app.Label_2.Text = '';
            app.cutSlider = uislider(app.RightPanel);
            app.cutSlider.Limits = [0 1];
            app.cutSlider.ValueChangedFcn = createCallbackFcn(app, @cutSliderCallback, true);
            app.cutEditFieldLabel = uilabel(app.RightPanel);
            app.cutEditFieldLabel.Text = 'cut';
            app.cutEditField = uieditfield(app.RightPanel, 'numeric');
            app.cutEditField.Limits = [0 Inf];
            app.cutEditField.ValueChangedFcn = createCallbackFcn(app, @cutEditFieldCallback, true);
            app.regionsizeEditFieldLabel = uilabel(app.RightPanel);
            app.regionsizeEditFieldLabel.Text = 'region size';
            app.regionsizeEditField = uieditfield(app.RightPanel, 'numeric');
            app.regionsizeEditField.RoundFractionalValues = 'on';
            app.regionsizeEditField.ValueChangedFcn = createCallbackFcn(app, @editSubimageSizeCallback, true);
            app.regionsizeSlider = uislider(app.RightPanel);
            app.regionsizeSlider.ValueChangedFcn = createCallbackFcn(app, @sliderSubimageSizeCallback, true);
            app.sizesub = uilabel(app.RightPanel);
            app.sizes = uicheckbox(app.RightPanel);
            app.adv = uicheckbox(app.RightPanel);
            app.adv.Text = 'Check Box2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end